classdef AppVisonV12_Eng < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalibratorTab                   matlab.ui.container.Tab
        DevelopedbyIsraelSantacruzMondragnLabel_2  matlab.ui.control.Label
        CargarDatos_2                   matlab.ui.container.Panel
        CParmetros                      matlab.ui.control.Button
        CargarDatos                     matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        ImagesButton                    matlab.ui.control.Button
        UploadinfoPanel                 matlab.ui.container.Panel
        Error                           matlab.ui.control.NumericEditField
        ErrormLabel                     matlab.ui.control.Label
        Pixel_size                      matlab.ui.control.NumericEditField
        TamaodelpixeldelacmaramLabel    matlab.ui.control.Label
        Patron                          matlab.ui.control.NumericEditField
        DimensionesdelpatrnmmLabel      matlab.ui.control.Label
        InformationPanel                matlab.ui.container.Panel
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        DatosCal                        matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        AutoTab                         matlab.ui.container.Tab
        DevelopedbyIsraelSantacruzMondragnLabel_7  matlab.ui.control.Label
        CargarDatos_3                   matlab.ui.container.Panel
        GuardarCoo                      matlab.ui.control.Button
        CPuntos                         matlab.ui.control.Table
        TypeofmeasurementPanel          matlab.ui.container.Panel
        M1_O_Auto                       matlab.ui.control.CheckBox
        M1_C_Auto                       matlab.ui.control.CheckBox
        Lamp_5_Auto                     matlab.ui.control.Lamp
        Lamp_5Label_2                   matlab.ui.control.Label
        Lamp_4_Auto                     matlab.ui.control.Lamp
        Lamp_4Label_2                   matlab.ui.control.Label
        M2_Auto                         matlab.ui.control.CheckBox
        M1_Auto                         matlab.ui.control.CheckBox
        ToolformeasuringdistanceandangleAutomaticLabel  matlab.ui.control.Label
        InformacinPanel_Auto            matlab.ui.container.Panel
        EditField_Auto                  matlab.ui.control.EditField
        EditField_Auto2                 matlab.ui.control.EditField
        FitPanel                        matlab.ui.container.Panel
        Coor                            matlab.ui.control.CheckBox
        SizeL                           matlab.ui.control.NumericEditField
        SizeletterLabel                 matlab.ui.control.Label
        ValY                            matlab.ui.control.NumericEditField
        ValYpixLabel                    matlab.ui.control.Label
        ValX                            matlab.ui.control.NumericEditField
        ValXpixLabel                    matlab.ui.control.Label
        HY                              matlab.ui.control.NumericEditField
        HistoenyLabel                   matlab.ui.control.Label
        HX                              matlab.ui.control.NumericEditField
        HistoenxLabel                   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        RadioMax                        matlab.ui.control.NumericEditField
        RadioMaxLabel                   matlab.ui.control.Label
        RadioMin                        matlab.ui.control.NumericEditField
        RadioMinLabel                   matlab.ui.control.Label
        Ruido                           matlab.ui.control.NumericEditField
        RuidoLabel                      matlab.ui.control.Label
        Bina                            matlab.ui.control.NumericEditField
        BinarizarLabel                  matlab.ui.control.Label
        Offset_x                        matlab.ui.control.NumericEditField
        OffsetenxLabel                  matlab.ui.control.Label
        Offset_y                        matlab.ui.control.NumericEditField
        OffsetenyLabel                  matlab.ui.control.Label
        LoadImagePanel                  matlab.ui.container.Panel
        No_Auto                         matlab.ui.control.Button
        ProcessimageLabel               matlab.ui.control.Label
        ImagenButton_Auto               matlab.ui.control.Button
        AngleWOCalibTab                 matlab.ui.container.Tab
        DevelopedbyIsraelSantacruzMondragnLabel_3  matlab.ui.control.Label
        ToolformeasuringanglesLabel     matlab.ui.control.Label
        InformationPanel_2              matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        LoadImagePanel_2                matlab.ui.container.Panel
        Crosshair_angle                 matlab.ui.control.CheckBox
        No_angle                        matlab.ui.control.Button
        Si_angle                        matlab.ui.control.Button
        DoyouwanttooverlayanimageLabel  matlab.ui.control.Label
        ImageButton                     matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        phi                             matlab.ui.control.NumericEditField
        nguloentreABLabel               matlab.ui.control.Label
        beta                            matlab.ui.control.NumericEditField
        nguloentreCALabel               matlab.ui.control.Label
        alpha                           matlab.ui.control.NumericEditField
        nguloentreCBLabel               matlab.ui.control.Label
        DistanceTab                     matlab.ui.container.Tab
        NumberofmeasurementsPanel_2     matlab.ui.container.Panel
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_2Label                     matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_3Label                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        M3                              matlab.ui.control.CheckBox
        M2                              matlab.ui.control.CheckBox
        M1                              matlab.ui.control.CheckBox
        ToolformeasuringdistanceLabel   matlab.ui.control.Label
        InformationPanel_3              matlab.ui.container.Panel
        EditField_6                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        DevelopedbyIsraelSantacruzMondragnLabel  matlab.ui.control.Label
        ResultsPanel_2                  matlab.ui.container.Panel
        Dis3                            matlab.ui.control.NumericEditField
        Distancia3mmLabel               matlab.ui.control.Label
        Dis2                            matlab.ui.control.NumericEditField
        Distancia2mmLabel               matlab.ui.control.Label
        Dis1                            matlab.ui.control.NumericEditField
        Distancia1mmLabel               matlab.ui.control.Label
        LoadImagePanel_3                matlab.ui.container.Panel
        Crosshair_Dis                   matlab.ui.control.CheckBox
        No                              matlab.ui.control.Button
        Si                              matlab.ui.control.Button
        DoyouwanttooverlayanimageLabel_2  matlab.ui.control.Label
        ImageButton_2                   matlab.ui.control.Button
        CoordinatesTab                  matlab.ui.container.Tab
        DevelopedbyIsraelSantacruzMondragnLabel_6  matlab.ui.control.Label
        NumberofmeasurementsPanel       matlab.ui.container.Panel
        Lamp_5_coo                      matlab.ui.control.Lamp
        Lamp_6Label_2                   matlab.ui.control.Label
        M4_2                            matlab.ui.control.CheckBox
        Lamp_4_coo                      matlab.ui.control.Lamp
        Lamp_6Label                     matlab.ui.control.Label
        Lamp_3_coo                      matlab.ui.control.Lamp
        Lamp_5Label                     matlab.ui.control.Label
        Lamp_2_coo                      matlab.ui.control.Lamp
        Lamp_4Label                     matlab.ui.control.Label
        M3_2                            matlab.ui.control.CheckBox
        M2_2                            matlab.ui.control.CheckBox
        M1_2                            matlab.ui.control.CheckBox
        TooltolocatepointsofinteresLabel  matlab.ui.control.Label
        InformationPanel_6              matlab.ui.container.Panel
        EditField_10                    matlab.ui.control.EditField
        EditField_9                     matlab.ui.control.EditField
        ResultsPanel_3                  matlab.ui.container.Panel
        Coord_5_x                       matlab.ui.control.NumericEditField
        P5xymmLabel                     matlab.ui.control.Label
        Coord_5_y                       matlab.ui.control.NumericEditField
        Coord_4_x                       matlab.ui.control.NumericEditField
        P4xymmLabel                     matlab.ui.control.Label
        Coord_3_x                       matlab.ui.control.NumericEditField
        P3xymmLabel                     matlab.ui.control.Label
        Coord_4_y                       matlab.ui.control.NumericEditField
        Coord_3_y                       matlab.ui.control.NumericEditField
        Coord_2_x                       matlab.ui.control.NumericEditField
        P2xymmLabel                     matlab.ui.control.Label
        Coord_2_y                       matlab.ui.control.NumericEditField
        Coord_1_y                       matlab.ui.control.NumericEditField
        Coord_1_x                       matlab.ui.control.NumericEditField
        P1xymmLabel                     matlab.ui.control.Label
        LoadImagePanel_6                matlab.ui.container.Panel
        Crosshair_coor                  matlab.ui.control.CheckBox
        Procesar                        matlab.ui.control.Button
        ImageButton_3                   matlab.ui.control.Button
        KinematicsTab                   matlab.ui.container.Tab
        DevelopedbyIsraelSantacruzMondragnLabel_4  matlab.ui.control.Label
        ResultsAnglePanel               matlab.ui.container.Panel
        phi_2                           matlab.ui.control.NumericEditField
        nguloentreABLabel_2             matlab.ui.control.Label
        beta_2                          matlab.ui.control.NumericEditField
        nguloentreCALabel_2             matlab.ui.control.Label
        alpha_2                         matlab.ui.control.NumericEditField
        nguloentreCBLabel_2             matlab.ui.control.Label
        LoaddataPanel                   matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        Radio                           matlab.ui.control.NumericEditField
        RadiommLabel                    matlab.ui.control.Label
        z                               matlab.ui.control.NumericEditField
        zmmLabel                        matlab.ui.control.Label
        angulo                          matlab.ui.control.NumericEditField
        nguloLabel                      matlab.ui.control.Label
        y                               matlab.ui.control.NumericEditField
        ymmLabel                        matlab.ui.control.Label
        x                               matlab.ui.control.NumericEditField
        xmmLabel                        matlab.ui.control.Label
        Toolfordeterminingthe2DkinematicsofasoftrobotLabel  matlab.ui.control.Label
        InformationPanel_4              matlab.ui.container.Panel
        EditField_8                     matlab.ui.control.EditField
        EditField_7                     matlab.ui.control.EditField
        LoadImagePanel_4                matlab.ui.container.Panel
        Crosshair_K                     matlab.ui.control.CheckBox
        Derecha                         matlab.ui.control.Button
        Izquierda                       matlab.ui.control.Button
        ThemovementistowardsLabel       matlab.ui.control.Label
        Imagen_cin                      matlab.ui.control.Button
        ResultsDistancePanel            matlab.ui.container.Panel
        phi_dist                        matlab.ui.control.NumericEditField
        DistanciadeAmmLabel             matlab.ui.control.Label
        beta_dist                       matlab.ui.control.NumericEditField
        DistanciadeBmmLabel             matlab.ui.control.Label
        alpha_dist                      matlab.ui.control.NumericEditField
        DistanciadeCmmLabel             matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        KinematicsWOCalibTab            matlab.ui.container.Tab
        DevelopedbyIsraelSantacruzMondragnLabel_5  matlab.ui.control.Label
        ResultsAnglePanel_2             matlab.ui.container.Panel
        phi_3                           matlab.ui.control.NumericEditField
        nguloentreABLabel_3             matlab.ui.control.Label
        beta_3                          matlab.ui.control.NumericEditField
        nguloentreCALabel_3             matlab.ui.control.Label
        alpha_3                         matlab.ui.control.NumericEditField
        nguloentreCBLabel_3             matlab.ui.control.Label
        LoaddataPanel_2                 matlab.ui.container.Panel
        CalculateButton_2               matlab.ui.control.Button
        Radio_2                         matlab.ui.control.NumericEditField
        RadiopxLabel                    matlab.ui.control.Label
        z_2                             matlab.ui.control.NumericEditField
        zpxLabel                        matlab.ui.control.Label
        angulo_2                        matlab.ui.control.NumericEditField
        nguloLabel_2                    matlab.ui.control.Label
        y_2                             matlab.ui.control.NumericEditField
        ypxLabel                        matlab.ui.control.Label
        x_2                             matlab.ui.control.NumericEditField
        xpxLabel                        matlab.ui.control.Label
        Toolfordeterminingthe2DkinematicsofasoftrobotLabel_2  matlab.ui.control.Label
        InformationPanel_5              matlab.ui.container.Panel
        EditField_12                    matlab.ui.control.EditField
        EditField_11                    matlab.ui.control.EditField
        LoadImagePanel_5                matlab.ui.container.Panel
        Crosshair_k_WO                  matlab.ui.control.CheckBox
        Derecha_2                       matlab.ui.control.Button
        Izquierda_2                     matlab.ui.control.Button
        ThemovementistowardsLabel_2     matlab.ui.control.Label
        Imagen_cin_2                    matlab.ui.control.Button
        ResultsDistancePanel_2          matlab.ui.container.Panel
        phi_dist_2                      matlab.ui.control.NumericEditField
        DistanciadeApxLabel             matlab.ui.control.Label
        beta_dist_2                     matlab.ui.control.NumericEditField
        DistanciadeBpxLabel             matlab.ui.control.Label
        alpha_dist_2                    matlab.ui.control.NumericEditField
        DistanciadeCpxLabel             matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        ContactTab                      matlab.ui.container.Tab
        ContactInfoPanel                matlab.ui.container.Panel
        emailisrastacruzgmailcomLabel   matlab.ui.control.Label
        DevelopedbyIsraelSantacruzMondragnLabel_8  matlab.ui.control.Label
    end


    methods (Access = private)


        function results = Cal(app)

            global imageFileNames imagePoints boardSize imagesUsed ...
                estimationErrors cameraParams worldPoints R t patron...
                squareSize Pixel_s cal_cargada

            patron=app.Patron.Value;

            % Detect checkerboards in images
            [imagePoints, boardSize, imagesUsed] = detectCheckerboardPoints(imageFileNames);
            imageFileNames = imageFileNames(imagesUsed);


            % Read the first image to obtain image size
            originalImage = imread(imageFileNames{1});
            [mrows, ncols, ~] = size(originalImage);

            % Generate world coordinates of the corners of the squares

            squareSize = patron; %37.5;  % in units of 'millimeters'
            worldPoints = generateCheckerboardPoints(boardSize, squareSize);

            % Calibrate the camera
            [cameraParams, imagesUsed, estimationErrors] = estimateCameraParameters(imagePoints, worldPoints, ...
                'EstimateSkew', false, 'EstimateTangentialDistortion', true, ...
                'NumRadialDistortionCoefficients', 3, 'WorldUnits', 'millimeters', ...
                'InitialIntrinsicMatrix', [], 'InitialRadialDistortion', [], ...
                'ImageSize', [mrows, ncols]);

            % View reprojection errors
            showReprojectionErrors(cameraParams,'Parent', app.UIAxes);

            % Visualize pattern locations
            %showExtrinsics(cameraParams, 'CameraCentric','Parent', app.UIAxes_2);
            h2=figure;

            if cal_cargada==1
                showExtrinsics(cameraParams.cameraParams, 'CameraCentric');
            else
                showExtrinsics(cameraParams, 'CameraCentric');           
            end
            
            imOrig = imread(imageFileNames{1});%11

            %-----Undistort the Image-----%
            [im, newOrigin] = undistortImage(imOrig, cameraParams, 'OutputView', 'full');

            %-----Compute Extrinsics-----%
            % Detect the checkerboard.
            %[imagePoints, boardSize] = detectCheckerboardPoints(im);
            imagePoints=imagePoints(:,:,1);

            % Adjust the imagePoints so that they are expressed in the coordinate system
            % used in the original image, before it was undistorted.  This adjustment
            % makes it compatible with the cameraParameters object computed for the original image.
            imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints

            % Compute rotation and translation of the camera.
            [R, t] = extrinsics(imagePoints, worldPoints, cameraParams);

            app.EditField.BackgroundColor='green';
            app.EditField_2.BackgroundColor='green';
            %app.CalibrateButton.Enable= 'on';
            app.EditField_2.Value='Processing completed';
            app.EditField.Value='Press any tab to continue';
            app.ImageButton.Enable='on';
            app.CalibrateButton.BackgroundColor= 'green';

            app.EditField_3.Value='To load an image';
            app.EditField_4.Value='Press the button';
            app.EditField_6.Value='Select the number of';
            app.EditField_5.Value='measurements to be performed';
            app.EditField_3.BackgroundColor='green';
            app.EditField_4.BackgroundColor='green';
            app.EditField_6.BackgroundColor='green';
            app.EditField_5.BackgroundColor='green';

            error_Pix=cameraParams.MeanReprojectionError;
            Pixel_s=app.Pixel_size.Value;
            error=Pixel_s*error_Pix;

            app.Error.Value=error;
            app.ImagesButton.Enable= 'off';
            app.CalibrateButton.Enable= 'off';
            app.CParmetros.Enable='off';
            app.CParmetros.BackgroundColor='green';
            app.Patron.BackgroundColor='green';
            app.Pixel_size.BackgroundColor='green';
            app.Error.BackgroundColor='green';
            app.Pixel_size.Enable='off';
            app.Patron.Enable='off';

            app.EditField_8.BackgroundColor='green';
            app.EditField_7.BackgroundColor='green';
            app.EditField_8.Value='Press the button "Imagen" o añada las';
            app.EditField_7.Value='coordinates of the point of interest';
            app.EditField_10.Value='Select the number of';
            app.EditField_9.Value='measurements to be performed';

            app.CalculateButton.Enable='on';
            app.Radio.BackgroundColor='green';
            app.angulo.BackgroundColor='green';
            app.CalculateButton.BackgroundColor='yellow';
            app.Imagen_cin.BackgroundColor='yellow';
            app.Imagen_cin.Enable='on';

            app.M1.Enable='on';
            app.M2.Enable='on';
            app.M3.Enable='on';
            app.SaveButton.Enable='on';
            
            app.M1_2.Enable='on';
            app.M2_2.Enable='on';
            app.M3_2.Enable='on';
            app.M4_2.Enable='on';
            app.M1_Auto.Enable='on';
            %app.M2_Auto.Enable='on';
            
            app.EditField_Auto.Value='Select any';
            app.EditField_Auto2.Value='type of measurement';
            app.EditField_Auto.BackgroundColor='green';
            app.EditField_Auto2.BackgroundColor='green';
            

            %             save 'cameraParams.mat' cameraParams imagePoints squareSize ...
            %                 Pixel_s;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global cal_cargada cont_ang cont_dist cont_cin cont_cin_noCal Crosshair_Dis...
                Crosshair_angle Crosshair_coor Crosshair_K Crosshair_k_WO
            clc; close all;
            format bank; % solo mestra 2 dígitos
            app.Patron.Value=1;
            app.Pixel_size.Value=1;
            app.Error.Value=0;
            app.ImagesButton.BackgroundColor= 'yellow';
            app.CalibrateButton.Enable= 'off';
            app.CalibrateButton.BackgroundColor='yellow';
            app.ImageButton.Enable='on';
            app.ImageButton_2.Enable='off';
            app.EditField_2.Value='Press the "Img." or "Param." button';
            app.EditField.Value=' to start the calibration';
            app.EditField_3.Value='To load an image';
            app.EditField_4.Value='Press the button';
            app.EditField_3.BackgroundColor='green';
            app.EditField_4.BackgroundColor='green';

            app.EditField_6.Value='To enable this option';
            app.EditField_5.Value='you need to perform the calibration';
            app.EditField_Auto.Value='To enable this option';
            app.EditField_Auto2.Value='you need to perform the calibration';
            app.EditField_12.BackgroundColor='green';
            app.EditField_11.BackgroundColor='green';
            app.EditField_12.Value='Press the button "Imagen" or add the';
            app.EditField_11.Value='coordinates of the point of interest';

            app.No.Enable='off';
            app.Si.Enable='off';
            app.No_angle.Enable='off';
            app.Si_angle.Enable='off';
            app.CParmetros.BackgroundColor='yellow';
            app.Patron.BackgroundColor='yellow';
            app.Pixel_size.BackgroundColor='yellow';
            app.Error.BackgroundColor='red';
            app.Error.Enable='off';
            app.EditField_8.Value='To enable this option';
            app.EditField_7.Value='you need to perform the calibration';
            app.EditField_10.Value='To enable this option';
            app.EditField_9.Value='you need to perform the calibration';
            app.EditField_7.BackgroundColor='yellow';
            app.EditField_8.BackgroundColor='yellow';
            app.EditField_10.BackgroundColor='yellow';
            app.EditField_9.BackgroundColor='yellow';
            app.x.BackgroundColor='yellow';
            app.y.BackgroundColor='yellow';
            app.z.BackgroundColor='yellow';
            app.Radio.BackgroundColor='red';
            app.angulo.BackgroundColor='red';
            app.CalculateButton.BackgroundColor='yellow';
            app.Radio.Enable='off';
            app.angulo.Enable='off';

            app.CalculateButton.Enable='off';
            app.Derecha.Enable='off';
            app.Izquierda.Enable='off';
            app.Imagen_cin.Enable='off';
            app.Imagen_cin.BackgroundColor='yellow';
            app.ImageButton.BackgroundColor='green';
            app.ImageButton_2.BackgroundColor='yellow';

            app.Si.BackgroundColor='yellow';
            app.No.BackgroundColor='yellow';
            app.No_angle.BackgroundColor='yellow';
            app.Si_angle.BackgroundColor='yellow';
            app.Izquierda.BackgroundColor='yellow';
            app.Derecha.BackgroundColor='yellow';

            app.alpha_2.BackgroundColor='yellow';
            app.beta_2.BackgroundColor='yellow';
            app.phi_2.BackgroundColor='yellow';
            app.alpha_dist.BackgroundColor='yellow';
            app.beta_dist.BackgroundColor='yellow';
            app.phi_dist.BackgroundColor='yellow';
            app.alpha.BackgroundColor='yellow';
            app.beta.BackgroundColor='yellow';
            app.phi.BackgroundColor='yellow';
            app.Dis1.BackgroundColor='yellow';
            app.Dis2.BackgroundColor='yellow';
            app.Dis3.BackgroundColor='yellow';
            app.M1.Value=false;
            app.M2.Value=false;
            app.M3.Value=false;
            app.M1.Enable='off';
            app.M2.Enable='off';
            app.M3.Enable='off';
            app.Lamp.Color='red';
            app.Lamp_2.Color='red';
            app.Lamp_3.Color='red';
            app.SaveButton.BackgroundColor='yellow';
            app.SaveButton.Enable='off';
            
            app.ImageButton_3.Enable='off';
            app.ImageButton_3.BackgroundColor='yellow';
            app.Procesar.Enable='off';
            app.Procesar.BackgroundColor='yellow';
            
            app.Lamp_2_coo.Color='red';
            app.Lamp_3_coo.Color='red';
            app.Lamp_4_coo.Color='red';
            app.Lamp_5_coo.Color='red';
            app.Lamp_4_Auto.Color='red';
            app.Lamp_5_Auto.Color='red';
            
            app.M1_2.Enable='off';
            app.M2_2.Enable='off';
            app.M3_2.Enable='off';
            app.M4_2.Enable='off';
            app.M1_Auto.Enable='off';
            app.M1_C_Auto.Enable='off';
            app.M1_O_Auto.Enable='off';
            app.M2_Auto.Enable='off';
            app.ImagenButton_Auto.Enable='off';
            app.No_Auto.Enable='off';
            app.Coord_1_x.BackgroundColor='yellow';
            app.Coord_1_y.BackgroundColor='yellow';
            app.Coord_2_x.BackgroundColor='yellow';
            app.Coord_2_y.BackgroundColor='yellow';
            app.Coord_3_x.BackgroundColor='yellow';
            app.Coord_3_y.BackgroundColor='yellow';
            app.Coord_4_x.BackgroundColor='yellow';
            app.Coord_4_y.BackgroundColor='yellow';
            app.Coord_5_x.BackgroundColor='yellow';
            app.Coord_5_y.BackgroundColor='yellow';
            
            app.ImagenButton_Auto.BackgroundColor='yellow';
            app.No_Auto.BackgroundColor='yellow';
            
            app.Offset_x.BackgroundColor='cyan';
            app.Offset_y.BackgroundColor='cyan';
            app.Offset_x.Value=0;
            app.Offset_y.Value=0;
            app.RadioMax.Value=55;
            app.RadioMin.Value=35;
            app.Bina.Value=0.45;
            app.Ruido.Value=150;
            app.RadioMax.BackgroundColor='cyan';
            app.RadioMin.BackgroundColor='cyan';
            app.Bina.BackgroundColor='cyan';
            app.Ruido.BackgroundColor='cyan';
            app.HX.BackgroundColor='cyan';
            app.HX.Value=70;
            app.HY.BackgroundColor='cyan';
            app.HY.Value=70;
            app.ValX.Value=0;
            app.ValY.Value=0;
            app.ValX.BackgroundColor='cyan';
            app.ValY.BackgroundColor='cyan';
            app.SizeL.Value=12;
            app.SizeL.BackgroundColor='cyan';
            app.Coor.Value=1;
            
            app.Offset_x.Enable='off';
            app.Offset_y.Enable='off';
            app.RadioMax.Enable='off';
            app.RadioMin.Enable='off';
            app.Bina.Enable='off';
            app.Ruido.Enable='off';
            app.HY.Enable='off';
            app.HX.Enable='off';
            app.ValX.Enable='off';
            app.ValY.Enable='off';
            app.SizeL.Enable='off';
            app.Coor.Enable='off';
            
            app.GuardarCoo.Enable='off';
            app.GuardarCoo.BackgroundColor='yellow';

            app.x_2.BackgroundColor='green';
            app.y_2.BackgroundColor='green';
            app.z_2.BackgroundColor='green';
            app.CalculateButton_2.BackgroundColor='green';
            app.Radio_2.BackgroundColor='red';
            app.angulo_2.BackgroundColor='red';
            app.Radio_2.Enable="off";
            app.angulo_2.Enable="off";
            app.Derecha_2.BackgroundColor='yellow';
            app.Izquierda_2.BackgroundColor='yellow';
            app.Imagen_cin_2.BackgroundColor='green';
            app.Derecha_2.Enable="off";
            app.Izquierda_2.Enable="off";

            app.phi_dist_2.BackgroundColor='green';
            app.beta_dist_2.BackgroundColor='green';
            app.alpha_dist_2.BackgroundColor='green';
            app.phi_3.BackgroundColor='green';
            app.beta_3.BackgroundColor='green';
            app.alpha_3.BackgroundColor='green';

            cal_cargada=0;
            cont_ang=0;
            cont_dist=0;
            cont_cin=0;
            cont_cin_noCal=0;
            Crosshair_Dis=0;
            Crosshair_angle=0;
            Crosshair_coor=0;
            Crosshair_K=0;
            Crosshair_k_WO=0;
            
            %app.Mediciones.SelectedObject='1 Medición';

        end

        % Button pushed function: ImagesButton
        function ImagesButtonPushed(app, event)
            global imageFileNames imageFile
            %clc; close all; clear all;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            [file, path] = uigetfile(tipos,'MultiSelect','On');
            if  isequal(file,0)
                disp('Selection canceled');
            else

                for i=1:length(file)
                    imageFile{i} = fullfile(path, file(i));
                    imageFileNames{i}= cell2mat(imageFile{i});
                end

                app.CalibrateButton.Enable= 'on';
                app.ImagesButton.BackgroundColor= 'green';
                app.CalibrateButton.BackgroundColor= 'yellow';
                app.EditField_2.Value='Image loading completed';
                app.EditField.Value='Press "Calibrate" to continue';
                app.EditField.BackgroundColor='yellow';
                app.EditField_2.BackgroundColor='yellow';

            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)


            app.CalibrateButton.Enable='off';
            app.EditField.Value='Processing images';
            app.EditField_2.Value='Please wait';

            Cal(app);

        end

        % Button pushed function: ImageButton
        function ImagenAngle(app, event)
            global img_angle magnification_angle cont_ang a_ang

            close all;
            magnification_angle = 25;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
                        
            if cont_ang==0
                [file, path] = uigetfile(tipos,'Select a image');
            else
                [file, path] = uigetfile(tipos,'Select a image',a_ang);
            end
            
            
            if  isequal(file,0)
                disp('Selection canceled');
            else
                imagepath = fullfile(path,file);
                img_angle = imread (imagepath);
                %uax = uiaxes;
                %imshow(img)
                %hold (app.UIAxes,"on")

                app.No_angle.Enable='on';
                app.Si_angle.Enable='on';
                app.ImageButton.BackgroundColor='green';
                app.No_angle.BackgroundColor='cyan';
                app.Si_angle.BackgroundColor='cyan';
                %app.M2.Value=true;
                cont_ang=1;
                a_ang=path;
            end
        end

        % Button pushed function: ImageButton_2
        function ImagenDist(app, event)
            global img magnification a_dist cont_dist

            close all;
            magnification = 25;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            
            if cont_dist==0
                [file, path] = uigetfile(tipos,'Select a image');
            else
                [file, path] = uigetfile(tipos,'Select a image',a_dist);
            end

            if  isequal(file,0)
                disp('Selection canceled');
            else
                imagepath = fullfile(path,file);
                img = imread (imagepath);
                %uax = uiaxes;
                %imshow(img)
                %hold (app.UIAxes,"on")

                app.No.Enable='on';
                app.Si.Enable='on';

                app.ImageButton_2.BackgroundColor='green';
                app.Si.BackgroundColor='cyan';
                app.No.BackgroundColor='cyan';
                cont_dist=1;
                a_dist=path;
            end
        end

        % Button pushed function: No
        function NoButton(app, event)
            global cameraParams R t newOrigin cameraLocation img ...
                magnification cal_cargada Crosshair_Dis
            
            close all;

            if cal_cargada == 1

                [im, newOrigin] = undistortImage(img, cameraParams.cameraParams, 'OutputView', 'full');
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=cameraParams.imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams.cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');
                                
                if app.M1.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=0;
                    app.Dis3.Value=0;
                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='red';
                    app.Dis3.BackgroundColor='red';
                    
                end

                if app.M2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    
                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=0;
                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='red';

                    
                end

                if app.M3.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    
                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    
                    h4 = drawpoint('Label', 'P5', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P4= h4.Position(1,:); %Points coordinates (points)
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P4(1,1) P4(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    h5 = drawpoint('Label', 'P6', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P5= h5.Position(1,:);
                    drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'D3', 'LabelAlpha',0,"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);
                    P4_world  = pointsToWorld(cameraParams.cameraParams, R, t, P4);
                    P5_world  = pointsToWorld(cameraParams.cameraParams, R, t, P5);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);
                    distance3 = norm(P4_world - P5_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=distance3;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='green';

                end

            else


                [im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                if app.M1.Value==1
                    
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);

                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=0;
                    app.Dis3.Value=0;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='red';
                    app.Dis3.BackgroundColor='red';

                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                end

                if app.M2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    
                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=0;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='red';

                    
                end

                if app.M3.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    
                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    
                    h4 = drawpoint('Label', 'P5', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P4= h4.Position(1,:); %Points coordinates (points)
                    if Crosshair_Dis==1
                        drawcrosshair('Position',[P4(1,1) P4(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    h5 = drawpoint('Label', 'P6', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P5= h5.Position(1,:);
                    drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'D3', 'LabelAlpha',0,"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams, R, t, P3);
                    P4_world  = pointsToWorld(cameraParams, R, t, P4);
                    P5_world  = pointsToWorld(cameraParams, R, t, P5);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);
                    distance3 = norm(P4_world - P5_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=distance3;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='green';

                end

            end


        end

        % Button pushed function: Si
        function SiButton(app, event)
            global img cameraParams R t newOrigin cameraLocation ...
                magnification cal_cargada a_dist Crosshair_Dis
            
            close all;

            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            [file, path] = uigetfile(tipos,'Select a image',a_dist);
            if  isequal(file,0)
                disp('Selection canceled');
            else
                if cal_cargada == 1
                    [im, newOrigin] = undistortImage(img, cameraParams.cameraParams, 'OutputView', 'full');
                    %-----Compute Extrinsics-----%
                    % Detect the checkerboard.
                    %Se comenta la linea de abajo para evitar problemas con
                    %imagenes que no tengan patrones
                    %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                    imagePoints=cameraParams.imagePoints(:,:,1);

                    % Adjust the imagePoints so that they are expressed in the coordinate system
                    % used in the original image, before it was undistorted.  This adjustment
                    % makes it compatible with the cameraParameters object computed for the original image.
                    imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                    WorldPoints=  cameraParams.cameraParams.WorldPoints;

                    % Compute rotation and translation of the camera.
                    [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams.cameraParams);

                    imagepath = fullfile(path,file);
                    img2 = imread (imagepath);

                    %P2=imread(img2);
                    P2= imadd(img2,-130);%Contraste
                    P_resp = imadd(img,P2);%union de imagenes
                    %imshow(P_resp,[])

                    [im, newOrigin] = undistortImage(P_resp, cameraParams.cameraParams, 'OutputView', 'full');
                    figure; imshow(im, 'InitialMagnification', magnification);
                    title('Undistorted Image');

                    if app.M1.Value==1
                        h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P0= h0.Position(1,:); %Points coordinates (points)
                        
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        
                        h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P1= h1.Position(1,:);
                        drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');


                        % Convert to world coordinates.
                        P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                        P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);

                        %scatter(x3,y3,'filled','DisplayName','2017')
                        % Compute the distance
                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        distance1 = norm(P0_world - P1_world);

                        app.Dis1.Value=distance1;
                        app.Dis2.Value=0;
                        app.Dis3.Value=0;
                        app.Dis1.BackgroundColor='green';
                        app.Dis2.BackgroundColor='red';
                        app.Dis3.BackgroundColor='red';

                        
                    end

                    if app.M2.Value==1
                        h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P0= h0.Position(1,:); %Points coordinates (points)
                        
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        
                        h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P1= h1.Position(1,:);
                        drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        
                        h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P2= h2.Position(1,:); %Points coordinates (points)
                        
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        
                        h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P3= h3.Position(1,:);
                        drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');

                        % Convert to world coordinates.
                        P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                        P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                        P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                        P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);

                        %scatter(x3,y3,'filled','DisplayName','2017')
                        % Compute the distance
                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        distance1 = norm(P0_world - P1_world);
                        distance2 = norm(P2_world - P3_world);

                        app.Dis1.Value=distance1;
                        app.Dis2.Value=distance2;
                        app.Dis3.Value=0;
                        app.Dis1.BackgroundColor='green';
                        app.Dis2.BackgroundColor='green';
                        app.Dis3.BackgroundColor='red';

                        
                    end

                    if app.M3.Value==1
                        h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P0= h0.Position(1,:); %Points coordinates (points)
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P1= h1.Position(1,:);
                        drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        
                        h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P2= h2.Position(1,:); %Points coordinates (points)
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P3= h3.Position(1,:);
                        drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        
                        h4 = drawpoint('Label', 'P5', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P4= h4.Position(1,:); %Points coordinates (points)
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P4(1,1) P4(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        h5 = drawpoint('Label', 'P6', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P5= h5.Position(1,:);
                        drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'D3', 'LabelAlpha',0,"InteractionsAllowed",'none');


                        % Convert to world coordinates.
                        P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                        P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                        P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                        P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);
                        P4_world  = pointsToWorld(cameraParams.cameraParams, R, t, P4);
                        P5_world  = pointsToWorld(cameraParams.cameraParams, R, t, P5);

                        %scatter(x3,y3,'filled','DisplayName','2017')
                        % Compute the distance
                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        distance1 = norm(P0_world - P1_world);
                        distance2 = norm(P2_world - P3_world);
                        distance3 = norm(P4_world - P5_world);

                        app.Dis1.Value=distance1;
                        app.Dis2.Value=distance2;
                        app.Dis3.Value=distance3;

                        app.Dis1.BackgroundColor='green';
                        app.Dis2.BackgroundColor='green';
                        app.Dis3.BackgroundColor='green';

                        
                    end

                else
                    imagepath = fullfile(path,file);
                    img2 = imread (imagepath);

                    %P2=imread(img2);
                    P2= imadd(img2,-130);%Contraste
                    P_resp = imadd(img,P2);%union de imagenes
                    %imshow(P_resp,[])

                    [im, newOrigin] = undistortImage(P_resp, cameraParams, 'OutputView', 'full');
                    figure; imshow(im, 'InitialMagnification', magnification);
                    title('Undistorted Image');

                    if app.M1.Value==1
                        h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P0= h0.Position(1,:); %Points coordinates (points)
                        
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        
                        h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P1= h1.Position(1,:);
                        drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');


                        % Convert to world coordinates.
                        P0_world  = pointsToWorld(cameraParams, R, t, P0);
                        P1_world  = pointsToWorld(cameraParams, R, t, P1);

                        %scatter(x3,y3,'filled','DisplayName','2017')
                        % Compute the distance
                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        distance1 = norm(P0_world - P1_world);

                        app.Dis1.Value=distance1;
                        app.Dis2.Value=0;
                        app.Dis3.Value=0;

                        app.Dis1.BackgroundColor='green';
                        app.Dis2.BackgroundColor='red';
                        app.Dis3.BackgroundColor='red';

                        
                    end

                    if app.M2.Value==1
                        h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P0= h0.Position(1,:); %Points coordinates (points)
                        
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        
                        h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P1= h1.Position(1,:);
                        drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        
                        h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P2= h2.Position(1,:); %Points coordinates (points)
                        
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        
                        h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P3= h3.Position(1,:);
                        drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');

                        % Convert to world coordinates.
                        P0_world  = pointsToWorld(cameraParams, R, t, P0);
                        P1_world  = pointsToWorld(cameraParams, R, t, P1);
                        P2_world  = pointsToWorld(cameraParams, R, t, P2);
                        P3_world  = pointsToWorld(cameraParams, R, t, P3);

                        %scatter(x3,y3,'filled','DisplayName','2017')
                        % Compute the distance
                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        distance1 = norm(P0_world - P1_world);
                        distance2 = norm(P2_world - P3_world);

                        app.Dis1.Value=distance1;
                        app.Dis2.Value=distance2;
                        app.Dis3.Value=0;

                        app.Dis1.BackgroundColor='green';
                        app.Dis2.BackgroundColor='green';
                        app.Dis3.BackgroundColor='red';

                        
                    end

                    if app.M3.Value==1
                        h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P0= h0.Position(1,:); %Points coordinates (points)
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P1= h1.Position(1,:);
                        drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        
                        h2 = drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P2= h2.Position(1,:); %Points coordinates (points)
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        h3 = drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P3= h3.Position(1,:);
                        drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        
                        h4 = drawpoint('Label', 'P5', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P4= h4.Position(1,:); %Points coordinates (points)
                        if Crosshair_Dis==1
                            drawcrosshair('Position',[P4(1,1) P4(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                        end
                        h5 = drawpoint('Label', 'P6', 'LabelAlpha',0,"InteractionsAllowed",'none');
                        P5= h5.Position(1,:);
                        drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'D3', 'LabelAlpha',0,"InteractionsAllowed",'none');


                        % Convert to world coordinates.
                        P0_world  = pointsToWorld(cameraParams, R, t, P0);
                        P1_world  = pointsToWorld(cameraParams, R, t, P1);
                        P2_world  = pointsToWorld(cameraParams, R, t, P2);
                        P3_world  = pointsToWorld(cameraParams, R, t, P3);
                        P4_world  = pointsToWorld(cameraParams, R, t, P4);
                        P5_world  = pointsToWorld(cameraParams, R, t, P5);

                        %scatter(x3,y3,'filled','DisplayName','2017')
                        % Compute the distance
                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        distance1 = norm(P0_world - P1_world);
                        distance2 = norm(P2_world - P3_world);
                        distance3 = norm(P4_world - P5_world);

                        app.Dis1.Value=distance1;
                        app.Dis2.Value=distance2;
                        app.Dis3.Value=distance3;

                        app.Dis1.BackgroundColor='green';
                        app.Dis2.BackgroundColor='green';
                        app.Dis3.BackgroundColor='green';

                        
                    end
                end
            end
        end

        % Button pushed function: No_angle
        function NoButton_angle(app, event)
            global Alpha Beta Phi...
                cameraParams newOrigin img_angle magnification_angle...
                cal_cargada Crosshair_angle
            
                close all;

                figure; imshow(img_angle)

                h3 = drawpoint('Label', '1', 'LabelAlpha',0,"InteractionsAllowed",'none'); % Point selection
                P3= h3.Position(1,:); %Points coordinates
                if Crosshair_angle==1
                        drawcrosshair('Position',[P3(1,1) P3(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                                
                h4 = drawpoint('Label', '2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P4= h4.Position(1,:); %Points coordinates
                drawline('Position',[P3(1,1),P3(1,2);P4(1,1),P4(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');
                
                if Crosshair_angle==1
                        drawcrosshair('Position',[P4(1,1) P4(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                
                h5 = drawpoint('Label', '3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P5= h5.Position(1,:);

                drawline('Position',[P3(1,1),P3(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                C= norm(P3 - P4);% Points distance 'C'
                B= norm(P3 - P5);%'B'
                A= norm(P4 - P5);%'A'

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));
                app.alpha.Value=Alpha;
                app.beta.Value=Beta;
                app.phi.Value=Phi;

            app.alpha.BackgroundColor='green';
            app.beta.BackgroundColor='green';
            app.phi.BackgroundColor='green';
        end

        % Button pushed function: Si_angle
        function SiButton_angle(app, event)
            global img_angle cameraParams newOrigin magnification_angle...
                a_ang Crosshair_angle

            close all;
            
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            [file, path] = uigetfile(tipos,'Select a image',a_ang);
            if  isequal(file,0)
                disp('Selection canceled');
            else

                    imagepath = fullfile(path,file);
                    img2_angle = imread (imagepath);

                    %P2=imread(img2);
                    P2= imadd(img2_angle,-130);%Contraste
                    P_resp_angle = imadd(img_angle,P2);%union de imagenes
                    %imshow(P_resp,[])

                    figure;imshow(P_resp_angle)

                    h3 = drawpoint('Label', '1', 'LabelAlpha',0,"InteractionsAllowed",'none'); % Point selection
                    P3= h3.Position(1,:); %Points coordinates
                    if Crosshair_angle==1
                            drawcrosshair('Position',[P3(1,1) P3(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                                    
                    h4 = drawpoint('Label', '2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P4= h4.Position(1,:); %Points coordinates
                    drawline('Position',[P3(1,1),P3(1,2);P4(1,1),P4(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    if Crosshair_angle==1
                            drawcrosshair('Position',[P4(1,1) P4(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h5 = drawpoint('Label', '3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P5= h5.Position(1,:);
    
                    drawline('Position',[P3(1,1),P3(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                    C= norm(P3 - P4);% Points distance 'C'
                    B= norm(P3 - P5);%'B'
                    A= norm(P4 - P5);%'A'

                    Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                    Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                    Phi= acosd((C^2-B^2-A^2)/(-2*B*A));
                    app.alpha.Value=Alpha;
                    app.beta.Value=Beta;
                    app.phi.Value=Phi;
                
            end

            app.alpha.BackgroundColor='green';
            app.beta.BackgroundColor='green';
            app.phi.BackgroundColor='green';
        end

        % Button pushed function: CParmetros
        function CParmetrosButton(app, event)
            global cameraParams squareSize cal_cargada

            cal_cargada=1; %variable para saber si se cargaron los parametros
            tipos = {'*.mat'};
            [file, path] = uigetfile(tipos);
            if  isequal(file,0)
                disp('Selection canceled');
            else
                CamParamPath = fullfile(path,file);
                cameraParams = importdata(CamParamPath);

                % Generate world coordinates of the corners of the squares

                squareSize = cameraParams.squareSize; %37.5;  % in units of 'millimeters'

                % View reprojection errors
                showReprojectionErrors(cameraParams.cameraParams,'Parent', app.UIAxes);

                % Visualize pattern locations
                %showExtrinsics(cameraParams.cameraParams,'CameraCentric','Parent',app.UIAxes_2); % versión 2021
                h2=figure; %version 2023
                showExtrinsics(cameraParams.cameraParams, 'CameraCentric');

                app.EditField.BackgroundColor='green';
                app.EditField_2.BackgroundColor='green';
                app.ImagesButton.BackgroundColor= 'green';
                app.EditField_2.Value='Processing completed';
                app.EditField.Value='Press any tab to continue';
                app.ImageButton.Enable='on';
                app.CalibrateButton.BackgroundColor= 'green';
                app.CParmetros.BackgroundColor='green';
                app.EditField_3.Value='To load an image';
                app.EditField_4.Value='Press the button';
                app.EditField_3.BackgroundColor='green';
                app.EditField_4.BackgroundColor='green';

                app.EditField_6.Value='Select the number of';
                app.EditField_5.Value='measurements to be performed';
                app.EditField_3.BackgroundColor='green';
                app.EditField_4.BackgroundColor='green';
                app.EditField_6.BackgroundColor='green';
                app.EditField_5.BackgroundColor='green';
                app.ImagesButton.Enable= 'off';
                app.CalibrateButton.Enable= 'off';
                app.CParmetros.Enable='off';
                app.Patron.BackgroundColor='green';
                app.Pixel_size.BackgroundColor='green';
                app.Error.BackgroundColor='green';
                app.Pixel_size.Enable='off';
                app.Patron.Enable='off';

                app.EditField_8.BackgroundColor='green';
                app.EditField_7.BackgroundColor='green';
                app.EditField_8.Value='Press the button "Imagen" or add the';
                app.EditField_7.Value='coordinates of the point of interest';
                app.EditField_10.Value='Select the number of';
                app.EditField_9.Value='measurements to be performed';

                app.CalculateButton.Enable='on';
                app.Imagen_cin.Enable='on';
                app.M1.Enable='on';
                app.M2.Enable='on';
                app.M3.Enable='on';

                app.Patron.Value=squareSize;
                app.Pixel_size.Value= cameraParams.Pixel_s;
                error_Pix=cameraParams.cameraParams.MeanReprojectionError;
                error=squareSize*error_Pix;
                app.Error.Value=error;
                
                app.M1_2.Enable='on';
                app.M2_2.Enable='on';
                app.M3_2.Enable='on';
                app.M4_2.Enable='on';
                app.M1_Auto.Enable='on';
                %app.M2_Auto.Enable='on';
                
                app.EditField_Auto.Value='Select any';
                app.EditField_Auto2.Value='type of measurement';
                app.EditField_Auto.BackgroundColor='green';
                app.EditField_Auto2.BackgroundColor='green';

            end

        end

        % Button pushed function: CalculateButton
        function Calcular(app, event)
            global r
            x_cin=app.x.Value;
            y_cin=app.y.Value;
            z_cin=app.z.Value;

            k=(2*(x_cin^2+y_cin^2)^0.5)/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
            r=1/k; %Radio de la curva,
            theta_cin=acosd(1-(k*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación

            app.Radio.Value=r;
            app.angulo.Value=theta_cin;

            app.x.BackgroundColor='green';
            app.y.BackgroundColor='green';
            app.z.BackgroundColor='green';
            app.Radio.BackgroundColor='green';
            app.angulo.BackgroundColor='green';
            app.CalculateButton.BackgroundColor='green';


        end

        % Button pushed function: Imagen_cin
        function Imagen_cinButton(app, event)
            global img_cin magnification a_cin cont_cin

            close all;
            magnification = 25;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            
            if cont_cin==0
                [file, path] = uigetfile(tipos,'Select a image');
            else
                [file, path] = uigetfile(tipos,'Select a image',a_cin);
            end
            
            if  isequal(file,0)
                disp('Selection canceled');
            else
                imagepath = fullfile(path,file);
                img_cin = imread (imagepath);
                %uax = uiaxes;
                %imshow(img)
                %hold (app.UIAxes,"on")

                app.Derecha.Enable='on';
                app.Izquierda.Enable='on';
                app.Imagen_cin.BackgroundColor='green';
                app.Derecha.BackgroundColor='cyan';
                app.Izquierda.BackgroundColor='cyan';
                
                cont_cin=1;
                a_cin=path;

            end
        end

        % Button pushed function: Derecha
        function DerechaButton(app, event)
            global cameraParams R t newOrigin cameraLocation img_cin ...
                magnification cal_cargada imagePoints Crosshair_K
            
            close all;

            if cal_cargada == 1
                [im, newOrigin] = undistortImage(img_cin, cameraParams.cameraParams, 'OutputView', 'full');
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=cameraParams.imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams.cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P0= h0.Position(1,:); %Points coordinates (points)
                    
                if Crosshair_K==1
                   drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                    
                h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P1= h1.Position(1,:);
                drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Compute the distance
                [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                %distance2 = norm(P0_world - P1_world) %Distancia en mm
                %P_norm=norm(P0 - P1)% Distancia en pixeles

                % Convert to world coordinates.
                P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);

                %--

                %Calculo de posición
                Pnuevo(1,1)=P1(1,1); % Coordenadas en x
                Pnuevo(1,2)=P0(1,2); % Coordenadas en z
                pos(1,1)= norm(P0-Pnuevo); %Distancia en x
                pos(1,2)=norm(Pnuevo-P1);  %Distancia en z

                z_cin=pos(1,2);% eje z es el eje vertical
                x_cin=pos(1,1); % También puede ser el eje y
                y_cin=0;% No esta en el espacio

                %---Cálculo en pixeles---%
                k_pix=(2*sqrt(x_cin^2+y_cin^2))/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
                r_pix=1/k_pix; %Radio de la curva en pixeles
                theta_cin_pix=acosd(1-(k_pix*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación


                P_ext(1,1)=r_pix+P0(1,1); % Se añade coordenadas al punto del radio del circulo
                P_ext(1,2)=P0(1,2);

                P_ext_world = pointsToWorld(cameraParams.cameraParams, R, t, P_ext);% Posición del punto nuevo
                %d=norm(P_ext_world-P0_world);% distancia del radio

                drawline('Position',[P0(1,1),P0(1,2);P_ext(1,1),P_ext(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P_ext(1,1),P_ext(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Distancias de la triangulación en pixeles y mm
                C_world=norm(P0_world - P1_world);
                B_world= norm(P_ext_world-P0_world);
                A_world= norm(P1_world-P_ext_world);

                C= norm(P0 - P1);% Points distance 'C'
                B= norm(P0 - P_ext);%'B'
                A= norm(P1 - P_ext);%'A'

                app.alpha_dist.Value=C_world;
                app.beta_dist.Value=B_world;
                app.phi_dist.Value=A_world;

                images.roi.Circle(gca,'Center',[P_ext(1,1) P_ext(1,2)],'Radius',B,"InteractionsAllowed",'none');

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));

                app.alpha_2.Value=Alpha;
                app.beta_2.Value=Beta;
                app.phi_2.Value=Phi;
                %app.AngEditField.Value=theta_cin_pix;

                %---Cálculo de psoción del P1---%
                Pos_world=pointsToWorld(cameraParams.cameraParams, R, t, Pnuevo);%Posición del punto nuevo
                x_cin_mm= norm(P0_world-Pos_world);
                z_cin_mm= norm(Pos_world-P1_world);
                y_cin_mm=0;

                app.x.Value=x_cin_mm;
                app.y.Value=y_cin_mm;
                app.z.Value=z_cin_mm;

                k_mm=(2*(x_cin_mm^2+y_cin_mm^2)^0.5)/(x_cin_mm^2+y_cin_mm^2+z_cin_mm^2);% K: Inversa del radio de la curva
                r_mm=1/k_mm; %Radio de la curva,
                theta_cin_mm=acosd(1-(k_mm*sqrt(x_cin_mm^2+y_cin_mm^2))); %Ángulo de deformación

                app.Radio.Value=r_mm;
                app.angulo.Value=theta_cin_mm;

            else

                [im, newOrigin] = undistortImage(img_cin, cameraParams, 'OutputView', 'full');
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P0= h0.Position(1,:); %Points coordinates (points)
                    
                if Crosshair_K==1
                   drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                    
                h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P1= h1.Position(1,:);
                drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Compute the distance
                [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                %distance2 = norm(P0_world - P1_world) %Distancia en mm
                %P_norm=norm(P0 - P1)% Distancia en pixeles

                % Convert to world coordinates.
                P0_world  = pointsToWorld(cameraParams, R, t, P0);
                P1_world  = pointsToWorld(cameraParams, R, t, P1);

                %--

                %Calculo de posición
                Pnuevo(1,1)=P1(1,1); % Coordenadas en x
                Pnuevo(1,2)=P0(1,2); % Coordenadas en z
                pos(1,1)= norm(P0-Pnuevo); %Distancia en x
                pos(1,2)=norm(Pnuevo-P1);  %Distancia en z

                z_cin=pos(1,2);% eje z es el eje vertical
                x_cin=pos(1,1); % También puede ser el eje y
                y_cin=0;% No esta en el espacio

                %---Cálculo en pixeles---%
                k_pix=(2*sqrt(x_cin^2+y_cin^2))/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
                r_pix=1/k_pix; %Radio de la curva en pixeles
                theta_cin_pix=acosd(1-(k_pix*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación


                P_ext(1,1)=r_pix+P0(1,1); % Se añade coordenadas al punto del radio del circulo
                P_ext(1,2)=P0(1,2);

                P_ext_world = pointsToWorld(cameraParams, R, t, P_ext);% Posición del punto nuevo
                %d=norm(P_ext_world-P0_world);% distancia del radio

                drawline('Position',[P0(1,1),P0(1,2);P_ext(1,1),P_ext(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P_ext(1,1),P_ext(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Distancias de la triangulación en pixeles y mm
                C_world=norm(P0_world - P1_world);
                B_world= norm(P_ext_world-P0_world);
                A_world= norm(P1_world-P_ext_world);

                C= norm(P0 - P1);% Points distance 'C'
                B= norm(P0 - P_ext);%'B'
                A= norm(P1 - P_ext);%'A'

                app.alpha_dist.Value=C_world;
                app.beta_dist.Value=B_world;
                app.phi_dist.Value=A_world;

                images.roi.Circle(gca,'Center',[P_ext(1,1) P_ext(1,2)],'Radius',B,"InteractionsAllowed",'none');

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));

                app.alpha_2.Value=Alpha;
                app.beta_2.Value=Beta;
                app.phi_2.Value=Phi;
                %app.AngEditField.Value=theta_cin_pix;

                %---Cálculo de psoción del P1---%
                Pos_world=pointsToWorld(cameraParams, R, t, Pnuevo);%Posición del punto nuevo
                x_cin_mm= norm(P0_world-Pos_world);
                z_cin_mm= norm(Pos_world-P1_world);
                y_cin_mm=0;

                app.x.Value=x_cin_mm;
                app.y.Value=y_cin_mm;
                app.z.Value=z_cin_mm;

                k_mm=(2*(x_cin_mm^2+y_cin_mm^2)^0.5)/(x_cin_mm^2+y_cin_mm^2+z_cin_mm^2);% K: Inversa del radio de la curva
                r_mm=1/k_mm; %Radio de la curva,
                theta_cin_mm=acosd(1-(k_mm*sqrt(x_cin_mm^2+y_cin_mm^2))); %Ángulo de deformación

                app.Radio.Value=r_mm;
                app.angulo.Value=theta_cin_mm;

            end

            app.x.BackgroundColor='green';
            app.y.BackgroundColor='green';
            app.z.BackgroundColor='green';
            app.Radio.BackgroundColor='magenta';
            app.angulo.BackgroundColor='magenta';
            app.CalculateButton.BackgroundColor='green';

            app.alpha_2.BackgroundColor='green';
            app.beta_2.BackgroundColor='green';
            app.phi_2.BackgroundColor='magenta';
            app.alpha_dist.BackgroundColor='green';
            app.beta_dist.BackgroundColor='magenta';
            app.phi_dist.BackgroundColor='green';
        end

        % Button pushed function: Izquierda
        function IzquierdaButton(app, event)
            global cameraParams R t newOrigin cameraLocation img_cin ...
                magnification cal_cargada imagePoints Crosshair_K
            
            close all;

            if cal_cargada == 1
                [im, newOrigin] = undistortImage(img_cin, cameraParams.cameraParams, 'OutputView', 'full');
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=cameraParams.imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams.cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P0= h0.Position(1,:); %Points coordinates (points)
                    
                if Crosshair_K==1
                   drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                    
                h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P1= h1.Position(1,:);
                drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Compute the distance
                [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                %distance2 = norm(P0_world - P1_world) %Distancia en mm
                %P_norm=norm(P0 - P1)% Distancia en pixeles

                % Convert to world coordinates.
                P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);

                %--

                %Calculo de posición
                Pnuevo(1,1)=P1(1,1); % Coordenadas en x
                Pnuevo(1,2)=P0(1,2); % Coordenadas en z
                pos(1,1)= norm(P0-Pnuevo); %Distancia en x
                pos(1,2)=norm(Pnuevo-P1);  %Distancia en z

                z_cin=pos(1,2);% eje z es el eje vertical
                x_cin=pos(1,1); % También puede ser el eje y
                y_cin=0;% No esta en el espacio

                %---Cálculo en pixeles---%
                k_pix=(2*sqrt(x_cin^2+y_cin^2))/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
                r_pix=1/k_pix; %Radio de la curva en pixeles
                theta_cin_pix=acosd(1-(k_pix*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación

                P_ext(1,1)=P0(1,1)-r_pix; % Se añade coordenadas al punto del radio del circulo
                P_ext(1,2)=P0(1,2);

                P_ext_world = pointsToWorld(cameraParams.cameraParams, R, t, P_ext);% Posición del punto nuevo
                %d=norm(P_ext_world-P0_world);% distancia del radio

                drawline('Position',[P0(1,1),P0(1,2);P_ext(1,1),P_ext(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P_ext(1,1),P_ext(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Distancias de la triangulación en pixeles y mm
                C_world=norm(P0_world - P1_world);
                B_world= norm(P_ext_world-P0_world);
                A_world= norm(P1_world-P_ext_world);

                C= norm(P0 - P1);% Points distance 'C'
                B= norm(P0 - P_ext);%'B'
                A= norm(P1 - P_ext);%'A'

                app.alpha_dist.Value=C_world;
                app.beta_dist.Value=B_world;
                app.phi_dist.Value=A_world;

                images.roi.Circle(gca,'Center',[P_ext(1,1) P_ext(1,2)],'Radius',B,"InteractionsAllowed",'none');

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));

                app.alpha_2.Value=Alpha;
                app.beta_2.Value=Beta;
                app.phi_2.Value=Phi;
                %app.AngEditField.Value=theta_cin_pix;

                %---Cálculo de psoción del P1---%
                Pos_world=pointsToWorld(cameraParams.cameraParams, R, t, Pnuevo);%Posición del punto nuevo
                x_cin_mm= norm(P0_world-Pos_world);
                z_cin_mm= norm(Pos_world-P1_world);
                y_cin_mm=0;

                app.x.Value=x_cin_mm;
                app.y.Value=y_cin_mm;
                app.z.Value=z_cin_mm;

                k_mm=(2*(x_cin_mm^2+y_cin_mm^2)^0.5)/(x_cin_mm^2+y_cin_mm^2+z_cin_mm^2);% K: Inversa del radio de la curva
                r_mm=1/k_mm; %Radio de la curva,
                theta_cin_mm=acosd(1-(k_mm*sqrt(x_cin_mm^2+y_cin_mm^2))); %Ángulo de deformación

                app.Radio.Value=r_mm;
                app.angulo.Value=theta_cin_mm;

            else

                [im, newOrigin] = undistortImage(img_cin, cameraParams, 'OutputView', 'full');
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P0= h0.Position(1,:); %Points coordinates (points)
                    
                if Crosshair_K==1
                   drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                    
                h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P1= h1.Position(1,:);
                drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Compute the distance
                [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                %distance2 = norm(P0_world - P1_world) %Distancia en mm
                %P_norm=norm(P0 - P1)% Distancia en pixeles

                % Convert to world coordinates.
                P0_world  = pointsToWorld(cameraParams, R, t, P0);
                P1_world  = pointsToWorld(cameraParams, R, t, P1);

                %--

                %Calculo de posición
                Pnuevo(1,1)=P1(1,1); % Coordenadas en x
                Pnuevo(1,2)=P0(1,2); % Coordenadas en z
                pos(1,1)= norm(P0-Pnuevo); %Distancia en x
                pos(1,2)=norm(Pnuevo-P1);  %Distancia en z

                z_cin=pos(1,2);% eje z es el eje vertical
                x_cin=pos(1,1); % También puede ser el eje y
                y_cin=0;% No esta en el espacio

                %---Cálculo en pixeles---%
                k_pix=(2*sqrt(x_cin^2+y_cin^2))/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
                r_pix=1/k_pix; %Radio de la curva en pixeles
                theta_cin_pix=acosd(1-(k_pix*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación

                P_ext(1,1)=P0(1,1)-r_pix; % Se añade coordenadas al punto del radio del circulo
                P_ext(1,2)=P0(1,2);

                P_ext_world = pointsToWorld(cameraParams, R, t, P_ext);% Posición del punto nuevo
                %d=norm(P_ext_world-P0_world);% distancia del radio

                drawline('Position',[P0(1,1),P0(1,2);P_ext(1,1),P_ext(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P_ext(1,1),P_ext(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                % Distancias de la triangulación en pixeles y mm
                C_world=norm(P0_world - P1_world);
                B_world= norm(P_ext_world-P0_world);
                A_world= norm(P1_world-P_ext_world);

                C= norm(P0 - P1);% Points distance 'C'
                B= norm(P0 - P_ext);%'B'
                A= norm(P1 - P_ext);%'A'

                app.alpha_dist.Value=C_world;
                app.beta_dist.Value=B_world;
                app.phi_dist.Value=A_world;

                images.roi.Circle(gca,'Center',[P_ext(1,1) P_ext(1,2)],'Radius',B,"InteractionsAllowed",'none');

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));

                app.alpha_2.Value=Alpha;
                app.beta_2.Value=Beta;
                app.phi_2.Value=Phi;
                %app.AngEditField.Value=theta_cin_pix;

                %---Cálculo de psoción del P1---%
                Pos_world=pointsToWorld(cameraParams, R, t, Pnuevo);%Posición del punto nuevo
                x_cin_mm= norm(P0_world-Pos_world);
                z_cin_mm= norm(Pos_world-P1_world);
                y_cin_mm=0;

                app.x.Value=x_cin_mm;
                app.y.Value=y_cin_mm;
                app.z.Value=z_cin_mm;

                k_mm=(2*(x_cin_mm^2+y_cin_mm^2)^0.5)/(x_cin_mm^2+y_cin_mm^2+z_cin_mm^2);% K: Inversa del radio de la curva
                r_mm=1/k_mm; %Radio de la curva,
                theta_cin_mm=acosd(1-(k_mm*sqrt(x_cin_mm^2+y_cin_mm^2))); %Ángulo de deformación

                app.Radio.Value=r_mm;
                app.angulo.Value=theta_cin_mm;

            end

            app.x.BackgroundColor='green';
            app.y.BackgroundColor='green';
            app.z.BackgroundColor='green';
            app.Radio.BackgroundColor='magenta';
            app.angulo.BackgroundColor='magenta';
            app.CalculateButton.BackgroundColor='green';

            app.alpha_2.BackgroundColor='green';
            app.beta_2.BackgroundColor='green';
            app.phi_2.BackgroundColor='magenta';
            app.alpha_dist.BackgroundColor='green';
            app.beta_dist.BackgroundColor='magenta';
            app.phi_dist.BackgroundColor='green';
        end

        % Value changed function: M1
        function M1ValueChanged(app, event)
            valueM1 = app.M1.Value;

            if valueM1==1
                app.Lamp.Color='green';
                app.Lamp_3.Color='red';
                app.Lamp_2.Color='red';
                app.M2.Value=0;
                app.M3.Value=0;
                app.ImageButton_2.Enable='on';
                app.EditField_6.Value='Press the button';
                app.EditField_5.Value='To load an image';
            end

        end

        % Value changed function: M2
        function M2ValueChanged(app, event)
            valueM2 = app.M2.Value;

            if valueM2==1
                app.Lamp.Color='red';
                app.Lamp_3.Color='green';
                app.Lamp_2.Color='red';
                app.M1.Value=0;
                app.M3.Value=0;
                app.ImageButton_2.Enable='on';
                app.EditField_6.Value='Press the button';
                app.EditField_5.Value='To load an image';
            end

        end

        % Value changed function: M3
        function M3ValueChanged(app, event)
            valueM3 = app.M3.Value;

            if valueM3==1
                app.Lamp.Color='red';
                app.Lamp_3.Color='red';
                app.Lamp_2.Color='green';
                app.M2.Value=0;
                app.M1.Value=0;
                app.ImageButton_2.Enable='on';
                app.EditField_6.Value='Press the button';
                app.EditField_5.Value='To load an image';
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global cameraParams imagePoints squareSize Pixel_s;

            Path = uigetdir('C:\');
            Name= '\cameraParams.mat';

            save(strcat(Path,Name),'cameraParams','imagePoints','squareSize','Pixel_s');

            app.SaveButton.BackgroundColor='green';
            app.SaveButton.Enable='off';
        end

        % Button pushed function: ImageButton_3
        function ImagenCoord(app, event)
            global img_coor magnification a_dist cont_dist

            close all;
            magnification = 25;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            
            if cont_dist==0
                [file, path] = uigetfile(tipos,'Select a image');
            else
                [file, path] = uigetfile(tipos,'Select a image',a_dist);
            end

            if  isequal(file,0)
                disp('Selection canceled');
            else
                imagepath = fullfile(path,file);
                img_coor = imread (imagepath);
                %uax = uiaxes;
                %imshow(img)
                %hold (app.UIAxes,"on")

                app.ImageButton_3.BackgroundColor='green';
                app.Procesar.Enable='on';
                cont_dist=1;
                a_dist=path;
                
                app.EditField_10.Value='Press "Process"';
                app.EditField_9.Value='to continue';
            end
        end

        % Value changed function: M1_2
        function M1_Coord(app, event)
            valueM1_coo = app.M1_2.Value;
            
            if valueM1_coo==1
                app.Lamp_2_coo.Color='green';
                app.Lamp_3_coo.Color='red';
                app.Lamp_4_coo.Color='red';
                app.Lamp_5_coo.Color='red';
                app.M2_2.Value=0;
                app.M3_2.Value=0;
                app.M4_2.Value=0;
                app.ImageButton_3.Enable='on';
                app.EditField_10.Value='Press the button "Imagen"';
                app.EditField_9.Value='To load a figure';
            end
            
        end

        % Value changed function: M2_2
        function M2_Coord(app, event)
            valueM2_coo = app.M2_2.Value;
            
            if valueM2_coo==1
                app.Lamp_2_coo.Color='red';
                app.Lamp_3_coo.Color='green';
                app.Lamp_4_coo.Color='red';
                app.Lamp_5_coo.Color='red';
                app.M1_2.Value=0;
                app.M3_2.Value=0;
                app.M4_2.Value=0;
                app.ImageButton_3.Enable='on';
                app.EditField_10.Value='Press the button "Imagen"';
                app.EditField_9.Value='To load a figure';
            end
            
        end

        % Value changed function: M3_2
        function M3_Coord(app, event)
            valueM3_coo = app.M3_2.Value;
            if valueM3_coo==1
                app.Lamp_2_coo.Color='red';
                app.Lamp_3_coo.Color='red';
                app.Lamp_4_coo.Color='green';
                app.Lamp_5_coo.Color='red';
                app.M1_2.Value=0;
                app.M2_2.Value=0;
                app.M4_2.Value=0;
                app.ImageButton_3.Enable='on';
                app.EditField_10.Value='Press the button "Imagen"';
                app.EditField_9.Value='To load a figure';
            end
        end

        % Button pushed function: Procesar
        function Process_imag(app, event)
            global cameraParams R t newOrigin cameraLocation img_coor ...
                magnification cal_cargada Crosshair_coor
            
            close all;

            if cal_cargada == 1

                [im, newOrigin] = undistortImage(img_coor, cameraParams.cameraParams, 'OutputView', 'full');
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=cameraParams.imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams.cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                if app.M1_2.Value==1
                    
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = 0;
                    Coord3_y = 0;
                    Coord4_x = 0;
                    Coord4_y = 0;
                    Coord5_x = 0;
                    Coord5_y = 0;

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='red';
                    app.Coord_3_y.BackgroundColor='red';
                    app.Coord_4_x.BackgroundColor='red';
                    app.Coord_4_y.BackgroundColor='red';
                    app.Coord_5_x.BackgroundColor='red';
                    app.Coord_5_y.BackgroundColor='red';

                end

                if app.M2_2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P1(1,1) P1(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h2= drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    %P3= h3.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                    %P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = P2_world(1,1);
                    Coord3_y = P2_world(1,2);
                    Coord4_x = 0;
                    Coord4_y = 0;
                    Coord5_x = 0;
                    Coord5_y = 0;

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='green';
                    app.Coord_3_y.BackgroundColor='green';
                    app.Coord_4_x.BackgroundColor='red';
                    app.Coord_4_y.BackgroundColor='red';
                    app.Coord_5_x.BackgroundColor='red';
                    app.Coord_5_y.BackgroundColor='red';

                end
                
                if app.M3_2.Value==1

                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P1(1,1) P1(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h2= drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h3=drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white',"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = P2_world(1,1);
                    Coord3_y = P2_world(1,2);
                    Coord4_x = P3_world(1,1);
                    Coord4_y = P3_world(1,2);
                    Coord5_x = 0;
                    Coord5_y = 0;

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='green';
                    app.Coord_3_y.BackgroundColor='green';
                    app.Coord_4_x.BackgroundColor='green';
                    app.Coord_4_y.BackgroundColor='green';
                    app.Coord_5_x.BackgroundColor='red';
                    app.Coord_5_y.BackgroundColor='red';

                end

                if app.M4_2.Value==1
                    
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P1(1,1) P1(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h2= drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h3=drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P3(1,1) P3(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h4=drawpoint('Label', 'P5', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P4= h4.Position(1,:); %Points coordinates (points)
                    drawline('Position',[P3(1,1),P3(1,2);P4(1,1),P4(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    %P5= h5.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);
                    P4_world  = pointsToWorld(cameraParams.cameraParams, R, t, P4);
                    %P5_world  = pointsToWorld(cameraParams.cameraParams, R, t, P5);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = P2_world(1,1);
                    Coord3_y = P2_world(1,2);
                    Coord4_x = P3_world(1,1);
                    Coord4_y = P3_world(1,2);
                    Coord5_x = P4_world(1,1);
                    Coord5_y = P4_world(1,2);

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='green';
                    app.Coord_3_y.BackgroundColor='green';
                    app.Coord_4_x.BackgroundColor='green';
                    app.Coord_4_y.BackgroundColor='green';
                    app.Coord_5_x.BackgroundColor='green';
                    app.Coord_5_y.BackgroundColor='green';
                    
                end

            else


                [im, newOrigin] = undistortImage(img_coor, cameraParams, 'OutputView', 'full');

                figure; imshow(im, 'InitialMagnification', magnification);
                title('Undistorted Image');

                if app.M1_2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = 0;
                    Coord3_y = 0;
                    Coord4_x = 0;
                    Coord4_y = 0;
                    Coord5_x = 0;
                    Coord5_y = 0;

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='red';
                    app.Coord_3_y.BackgroundColor='red';
                    app.Coord_4_x.BackgroundColor='red';
                    app.Coord_4_y.BackgroundColor='red';
                    app.Coord_5_x.BackgroundColor='red';
                    app.Coord_5_y.BackgroundColor='red';
                    
                end

                if app.M2_2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P1(1,1) P1(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h2= drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    %P3= h3.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    %P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = P2_world(1,1);
                    Coord3_y = P2_world(1,2);
                    Coord4_x = 0;
                    Coord4_y = 0;
                    Coord5_x = 0;
                    Coord5_y = 0;

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='green';
                    app.Coord_3_y.BackgroundColor='green';
                    app.Coord_4_x.BackgroundColor='red';
                    app.Coord_4_y.BackgroundColor='red';
                    app.Coord_5_x.BackgroundColor='red';
                    app.Coord_5_y.BackgroundColor='red';
                    
                end

                if app.M3_2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P1(1,1) P1(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h2= drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h3=drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white',"InteractionsAllowed",'none');

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = P2_world(1,1);
                    Coord3_y = P2_world(1,2);
                    Coord4_x = P3_world(1,1);
                    Coord4_y = P3_world(1,2);
                    Coord5_x = 0;
                    Coord5_y = 0;

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='green';
                    app.Coord_3_y.BackgroundColor='green';
                    app.Coord_4_x.BackgroundColor='green';
                    app.Coord_4_y.BackgroundColor='green';
                    app.Coord_5_x.BackgroundColor='red';
                    app.Coord_5_y.BackgroundColor='red';

                end
                
                if app.M4_2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P0= h0.Position(1,:); %Points coordinates (points)
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P1= h1.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P1(1,1) P1(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h2= drawpoint('Label', 'P3', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P2= h2.Position(1,:); %Points coordinates (points)
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P2(1,1) P2(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h3=drawpoint('Label', 'P4', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P3= h3.Position(1,:);
                    
                    if Crosshair_coor==1
                        drawcrosshair('Position',[P3(1,1) P3(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                    end
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    
                    h4=drawpoint('Label', 'P5', 'LabelAlpha',0,"InteractionsAllowed",'none');
                    P4= h4.Position(1,:); %Points coordinates (points)
                    drawline('Position',[P3(1,1),P3(1,2);P4(1,1),P4(1,2)],'StripeColor','white',"InteractionsAllowed",'none');
                    %P5= h5.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams, R, t, P3);
                    P4_world  = pointsToWorld(cameraParams, R, t, P4);
                    %P5_world  = pointsToWorld(cameraParams.cameraParams, R, t, P5);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    Coord1_x = P0_world(1,1);
                    Coord1_y = P0_world(1,2);
                    Coord2_x = P1_world(1,1);
                    Coord2_y = P1_world(1,2);
                    Coord3_x = P2_world(1,1);
                    Coord3_y = P2_world(1,2);
                    Coord4_x = P3_world(1,1);
                    Coord4_y = P3_world(1,2);
                    Coord5_x = P4_world(1,1);
                    Coord5_y = P4_world(1,2);

                    app.Coord_1_x.Value=Coord1_x;
                    app.Coord_1_y.Value=Coord1_y;
                    app.Coord_2_x.Value=Coord2_x;
                    app.Coord_2_y.Value=Coord2_y;
                    app.Coord_3_x.Value=Coord3_x;
                    app.Coord_3_y.Value=Coord3_y;
                    app.Coord_4_x.Value=Coord4_x;
                    app.Coord_4_y.Value=Coord4_y;
                    app.Coord_5_x.Value=Coord5_x;
                    app.Coord_5_y.Value=Coord5_y;
                    app.Coord_1_x.BackgroundColor='green';
                    app.Coord_1_y.BackgroundColor='green';
                    app.Coord_2_x.BackgroundColor='green';
                    app.Coord_2_y.BackgroundColor='green';
                    app.Coord_3_x.BackgroundColor='green';
                    app.Coord_3_y.BackgroundColor='green';
                    app.Coord_4_x.BackgroundColor='green';
                    app.Coord_4_y.BackgroundColor='green';
                    app.Coord_5_x.BackgroundColor='green';
                    app.Coord_5_y.BackgroundColor='green';

                    
                end
                
                
            end
            app.Procesar.BackgroundColor='green';
            app.EditField_10.Value='Presione "Imagen" para cargar';
            app.EditField_9.Value='una nueva figura';
        end

        % Value changed function: M4_2
        function M4_Coord(app, event)
            valueM4_coo = app.M4_2.Value;
            
            if valueM4_coo==1
                app.Lamp_2_coo.Color='red';
                app.Lamp_3_coo.Color='red';
                app.Lamp_4_coo.Color='red';
                app.M1_2.Value=0;
                app.M2_2.Value=0;
                app.M3_2.Value=0;
                app.ImageButton_3.Enable='on';
                app.EditField_10.Value='Press the button "Imagen"';
                app.EditField_9.Value='To load a figure';
                app.Lamp_5_coo.Color='green';
            end
        end

        % Value changed function: M1_Auto
        function M1_AutoValueChanged(app, event)
            valueM1_auto = app.M1_Auto.Value;
            
            if valueM1_auto == 1
                app.Lamp_4_Auto.Color='green';
                app.M2_Auto.Value=0;
                app.M1_C_Auto.Enable='on';
                app.M1_O_Auto.Enable='on';
                app.Lamp_5_Auto.Color='red';  
                
                app.EditField_Auto.Value='Press the button "Imagen"';
                app.EditField_Auto2.Value='To load a figure';
            
            end
        end

        % Value changed function: M2_Auto
        function M2_AutoValueChanged(app, event)
            global valueM2_auto
            valueM2_auto = app.M2_Auto.Value;
            
            if valueM2_auto == 1
                app.Lamp_4_Auto.Color='red';
                app.M1_Auto.Value=0;
                app.M1_O_Auto.Value=0;
                app.M1_C_Auto.Value=0;
                app.M1_C_Auto.Enable='off';
                app.M1_O_Auto.Enable='off';
                app.Lamp_5_Auto.Color='green';  
                
                app.EditField_Auto.Value='Press the button "Imagen"';
                app.EditField_Auto2.Value='To load a figure';
                app.ImagenButton_Auto.Enable='on';
                
                app.ImagenButton_Auto.BackgroundColor='cyan';
            end
        end

        % Value changed function: M1_C_Auto
        function M1_C_AutoValueChanged(app, event)
            global valueCirc
            valueCirc = app.M1_C_Auto.Value;
            
            if valueCirc == 1
                app.M1_O_Auto.Value=0;
                app.ImagenButton_Auto.Enable='on';
                app.ImagenButton_Auto.BackgroundColor='cyan';
                
                app.Offset_x.Enable='off';
                app.Offset_y.Enable='off';
                app.RadioMax.Enable='on';
                app.RadioMin.Enable='on';
                app.Bina.Enable='off';
                app.Ruido.Enable='off';
                app.HY.Enable='off';
                app.HX.Enable='off';
                app.ValX.Enable='on';
                app.ValY.Enable='on';
                app.SizeL.Enable='on';
                app.Coor.Enable='on';
            end
        end

        % Value changed function: M1_O_Auto
        function M1_O_AutoValueChanged(app, event)
            global valueOtro
            
            valueOtro = app.M1_O_Auto.Value;
            
            if valueOtro == 1
                app.M1_C_Auto.Value=0;
                app.ImagenButton_Auto.Enable='on';
                app.ImagenButton_Auto.BackgroundColor='cyan';
                
                app.Offset_x.Enable='on';
                app.Offset_y.Enable='on';
                app.HY.Enable='on';
                app.HX.Enable='on';
                app.RadioMax.Enable='off';
                app.RadioMin.Enable='off';
                app.Bina.Enable='on';
                app.Ruido.Enable='on';
                app.ValX.Enable='off';
                app.ValY.Enable='off';
                app.SizeL.Enable='off';
                app.Coor.Enable='off';
                app.GuardarCoo.Enable='off';
            end
        end

        % Button pushed function: ImagenButton_Auto
        function ImagenButton_AutoPushed(app, event)
            global img_auto magnification a_dist cont_dist

            close all;
            magnification = 25;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            
            if cont_dist==0
                [file, path] = uigetfile(tipos,'Select a image');
            else
                [file, path] = uigetfile(tipos,'Select a image',a_dist);
            end

            if  isequal(file,0)
                disp('Selection canceled');
            else
                imagepath = fullfile(path,file);
                img_auto = imread (imagepath);
                %uax = uiaxes;
                %imshow(img)
                %hold (app.UIAxes,"on")

                app.No_Auto.Enable='on';
                %app.Si_Auto.Enable='on';

                app.ImagenButton_Auto.BackgroundColor='green';
                %app.Si_Auto.BackgroundColor='cyan';
                app.No_Auto.BackgroundColor='cyan';
                cont_dist=1;
                a_dist=path;
            end
        end

        % Button pushed function: No_Auto
        function No_AutoButtonPushed(app, event)
            global cameraParams R t newOrigin cameraLocation img_auto ...
                magnification cal_cargada P_world
            
            close all;            

            app.GuardarCoo.BackgroundColor='yellow';
            app.GuardarCoo.Enable='on';
            

            if cal_cargada == 1
                
                %Se comenta todo lo que tenga que ver con undistortImage
                %ya que no detecta los circulos
                %[im, newOrigin] = undistortImage(img_auto,
                %cameraParams.cameraParams, 'OutputView', 'full'); 
                %-----Compute Extrinsics-----%
                % Detect the checkerboard.
                %Se comenta la linea de abajo para evitar problemas con
                %imagenes que no tengan patrones
                %[imagePoints, boardSize] = detectCheckerboardPoints(img);
                imagePoints=cameraParams.imagePoints(:,:,1);

                % Adjust the imagePoints so that they are expressed in the coordinate system
                % used in the original image, before it was undistorted.  This adjustment
                % makes it compatible with the cameraParameters object computed for the original image.
                %imagePoints = imagePoints + newOrigin; % adds newOrigin to every row of imagePoints
                WorldPoints=  cameraParams.cameraParams.WorldPoints;

                % Compute rotation and translation of the camera.
                [R, t] = extrinsics(imagePoints, WorldPoints, cameraParams.cameraParams);

                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(img_auto, 'InitialMagnification', magnification);
                title('Image Analysis');
                %A= imread(im);
                if app.M1_C_Auto.Value == 1
                    
                    Rmin = app.RadioMin.Value;
                    Rmax = app.RadioMax.Value;
                    %[centersBright, radiiBright] = imfindcircles(A,[Rmin Rmax],'ObjectPolarity','bright');
                    [centersDark, radiiDark] = imfindcircles(img_auto,[Rmin Rmax],'ObjectPolarity','dark');
                    %viscircles(centersBright, radiiBright,'Color','b'); %Para circulos claros
                    viscircles(centersDark, radiiDark,'LineStyle','--','LineWidth',1);
                    z=size(centersDark,1);
                    
                    cont=1;

                    orden=sortrows(centersDark()); % orden https://la.mathworks.com/help/matlab/math/reshaping-and-rearranging-arrays.html
                    
                    for a=1:1:z %% Genreación de líneas
                           
                       if cont<=z-1
                           drawline('Position',[[orden(cont,:)];[orden(cont+1,:)]],'StripeColor','white','LineWidth', 1.5, 'LabelAlpha',0);
                       end
                       
                       cont=cont+1;
                    
                    end
                    
                    for a=1:1:z %% Genreación de puntos
                       
                       p(a)=drawpoint('Position',[orden(a,:)],'Color', 'r', 'LabelAlpha',0);
                       h(a,:)=p(1,a).Position(1,:);
                       P_world(a,:)= pointsToWorld(cameraParams.cameraParams, R, t, h(a,:));
                       %% aqui agregar offset en cada plano %%
                       %drawpoint('Position',[orden(a,:)],'Color', 'r', 'LabelAlpha',0, 'Label', sprintf('%.2f',P_world(a,:)));
                       
                       if app.Coor.Value==1
                           text(orden(a,1)+app.ValX.Value+20,orden(a,2)-20-app.ValY.Value,sprintf('%.2f',P_world(a,1)),'Color', 'r','FontSize',app.SizeL.Value,'fontweight','bold');
                           text(orden(a,1)+app.ValX.Value+150,orden(a,2)-20-app.ValY.Value,',','Color', 'r','FontSize',app.SizeL.Value,'fontweight','bold');
                           text(orden(a,1)+app.ValX.Value+180,orden(a,2)-20-app.ValY.Value,sprintf('%.2f',P_world(a,2)),'Color', 'r','FontSize',app.SizeL.Value,'fontweight','bold');
                       end
                       
                    end
                   
                    app.CPuntos.Data= P_world,'%.2f';

%                     h0 = drawpoint('Label', 'P1', 'LabelAlpha',0);
%                     h1 = drawpoint('Label', 'P2', 'LabelAlpha',0);
% 
%                     P0= h0.Position(1,:); %Points coordinates (points)
%                     P1= h1.Position(1,:);

%                     % Convert to world coordinates.
%                     P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
%                     P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    %distance1 = norm(P0_world - P1_world);


                end

                if app.M1_O_Auto.Value == 1
                    
                    I = rgb2gray(img_auto);
                    %Aplicamos filtro para suavizar
                    I= wiener2(I, [50, 50]);
                    
                    %Binarizar Imagen (lo que este por encima del umbral va a ser blanco y por denajo negro)
                    I = imbinarize(I,app.Bina.Value); %Agregar valores dinámicos
                    
                    %Volvemos a elimar el ruido %Agregar valores dinámicos
                    I=bwareaopen(I,app.Ruido.Value); %Las regiones que tengan menos del humbral serán eliminadas
                    
                    %Rellenar espacios de los objetos
                    se=strel('disk',5);
                    I=imclose(I,se);
                    figure(5)
                    imshow(I)
                    
                    %Detección de objetos

                    [B,L] = bwboundaries(I,'holes'); %Encontrar límites
                    
                    % Detección de bordes
                    
                    mat=[B{2,:}]; %convertir cell en matriz del 2nd objeto
                    y=mat(:, 1);
                    x=mat(:, 2);
                    a=size(mat);
                    
                    for con=1:1: a(1,1)
                        if mat(con,1)>=0 && mat(con,1)<=1550
                            if mat(a,2) >=150
                                y2(con)=mat(con,1);
                                x2(con)=mat(con,2);
                                con=con+1;
                            end
                        end
                    end
                    
                    
                    x2=x2';
                    y2=y2';
                    z=[x2 y2];
                    
                    
                    %%% encuentra las lienas en x %%%
                    x_new=histogram(x2, 'BinWidth', 1)
                    val=size(x_new.Values);
                    
                    
                    cont3=1;
                    for cont2=1 :1: val(1,2) % encontrar los coordenadas de las  lineas en x
                        if x_new.Values(cont2)>= app.HX.Value
                            A(cont3)=x_new.Values(cont2);
                            Val_A(cont3)= x_new.BinEdges(cont2);
                            cont3=cont3+1;
                        end
                    end
                    A=A';
                    Val_A=Val_A';
                    
                    Tot=size(Val_A);
                    sum_x=1;
                    for coor=1:1: Tot(1,1) % Se obtienen las coordenas de los puntos mas comunes 
                        for con=1:1: a(1,1)
                            if z(con,1)==Val_A(coor)
                                Val_A_mod(sum_x,:)=[Val_A(coor) z(con,2)];
                                sum_x=sum_x+1;
                            end  
                        end
                    end
                    
                    Cont_rep=size(Val_A_mod);
                    eroor_up=Val_A_mod(1,1)+10;
                    error_down=Val_A_mod(1,1)-10;
                    i=1;
                    k=i;
                    for w=1:1:Cont_rep(1,1)
                        if Val_A_mod(w,1) <= eroor_up && Val_A_mod(w,1)>= error_down
                            L1(i,:)= Val_A_mod(w,:);
                            i=i+1;
                        else
                            L2(k,:)= Val_A_mod(w,:);
                            k=k+1;
                        end
                    end
                    
                    %%% encuentra las lienas en y %%%
                    y_new=histogram(y2, 'BinWidth', 1)
                    y_val=size(y_new.Values);
                    
                    conty=1;
                    for cont2_y=1 :1: y_val(1,2) % encontrar los coordenadas de las  lineas en x
                        if y_new.Values(cont2_y)>=app.HY.Value
                            Ay(conty)=y_new.Values(cont2_y);
                            Val_Ay(conty)= y_new.BinEdges(cont2_y);
                            conty=conty+1;
                        end
                    end
                    Ay=Ay';
                    Val_Ay=Val_Ay';
                    
                    Toty=size(Val_Ay);
                    sum_y=1;
                    for coory=1:1: Toty(1,1) % Se obtienen las coordenas de los puntos mas comunes 
                        for cony=1:1: a(1,1)
                            if z(cony,2)==Val_Ay(coory)
                                Val_A_mody(sum_y,:)=[z(cony,1) Val_Ay(coory)];
                                sum_y=sum_y+1;%genera la matriz con todas la corrdenandas que coincidan con Val_Ay
                            end  
                        end
                    end
                    
                    Cont_repy=size(Val_A_mody);
                    eroor_upy=Val_A_mody(1,2)+10;
                    error_downy=Val_A_mody(1,2)-10;
                    iy=1;
                    ky=iy;
                    for wy=1:1:Cont_repy(1,1)
                        if Val_A_mody(wy,2) <= eroor_upy && Val_A_mody(wy,2)>= error_downy
                            L3(iy,:)= Val_A_mody(wy,:);
                            iy=iy+1;
                        else
                            L4(ky,:)= Val_A_mody(wy,:);
                            ky=ky+1;
                        end
                    end
                    
                    %%% Termina busqueda comienza grafica %%%
                    
                    s1=size(L1);
                    s2=size(L2);
                    s3=size(L3);
                    s4=size(L4);
                    
                    if s1(1,1) > 1
                        P1=mean(L1);
                        
                    else
                        P1=L1;
                    end
                    
                    if s2(1,1) > 1
                        P2=mean(L2);
                    else
                        P2=L2;
                    end
                    
                    if s3(1,1) > 1
                        P3=mean(L3);
                    else
                        P3=L3;
                    end
                    
                    if s4(1,1) > 1
                        P4=mean(L4);
                    else
                        P4=L4;
                    end
                    
                    P2(1,2)=P1(1,2);
                    
                    P3(1,1)= P4(1,1);
                    
                    %%
                    imshow(label2rgb(L,@jet,[.5 .5 .5])) % Muestre la matriz de etiquetas y dibuje cada límite.
                    hold on
                    for k = 1:length(B)
                      boundary = B{k};
                      plot(boundary(:,2),boundary(:,1),'w','LineWidth',2)
                    end %se da color a cada una de las regiones de los objetos encontrados 
                    
                    stats = regionprops(L,'Area','Centroid');
                    
                    threshold = 0.94;
                    
                    % loop over the boundaries
                    for k = 1:length(B)
                    
                      % obtain (X,Y) boundary coordinates corresponding to label 'k'
                      boundary = B{k};
                    
                      % compute a simple estimate of the object's perimeter
                      delta_sq = diff(boundary).^2;    
                      perimeter = sum(sqrt(sum(delta_sq,2)));
                      
                      % obtain the area calculation corresponding to label 'k'
                      area = stats(k).Area;
                      
                      % compute the roundness metric
                      metric = 4*pi*area/perimeter^2;
                      
                      % display the results
                      metric_string = sprintf('%2.2f',metric);
                    
                      % mark objects above the threshold with a black circle
                      if metric > threshold
                        centroid = stats(k).Centroid;
                        plot(centroid(1),centroid(2),'ko');
                      end
                      
                      text(boundary(1,2)-35,boundary(1,1)+13,metric_string,'Color','y',...
                           'FontSize',14,'FontWeight','bold');
                      
                    end
                    
                    title(['Metrics Closer to 1 Indicate that ',...
                           'the Object is Approximately Round'])


%                     h0 = drawpoint('Label', 'P1', 'LabelAlpha',0);
%                     h1 = drawpoint('Label', 'P2', 'LabelAlpha',0);
%                     
%                     P0= h0.Position(1,:); %Points coordinates (points)
%                     P1= h1.Position(1,:);

                    % Convert to world coordinates.
                     P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                     P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                     P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                     P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);
                    
                   
                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                     distance1 = norm(P0_world - P1_world)+app.Offset_x.Value;
                     distance2 = norm(P2_world - P3_world)+app.Offset_y.Value;

                    drawline('Position',[P1(1,1),P1(1,2);P2(1,1),P2(1,2)],'StripeColor','white','Label', sprintf('%.2f',distance1), 'LabelAlpha',0);
                    drawline('Position',[P3(1,1),P3(1,2);P4(1,1),P4(1,2)],'StripeColor','white','Label', sprintf('%.2f',distance2), 'LabelAlpha',0);
                     
                end

                if app.M3.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0);
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0);

                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0);
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0);

                    h4 = drawpoint('Label', 'P5', 'LabelAlpha',0);
                    h5 = drawpoint('Label', 'P6', 'LabelAlpha',0);

                    P0= h0.Position(1,:); %Points coordinates (points)
                    P1= h1.Position(1,:);
                    P2= h2.Position(1,:); %Points coordinates (points)
                    P3= h3.Position(1,:);
                    P4= h4.Position(1,:); %Points coordinates (points)
                    P5= h5.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams.cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams.cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams.cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams.cameraParams, R, t, P3);
                    P4_world  = pointsToWorld(cameraParams.cameraParams, R, t, P4);
                    P5_world  = pointsToWorld(cameraParams.cameraParams, R, t, P5);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);
                    distance3 = norm(P4_world - P5_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=distance3;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='green';

                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0);
                    drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'D3', 'LabelAlpha',0);
                end

            else


                %[im, newOrigin] = undistortImage(img, cameraParams, 'OutputView', 'full');

                figure; imshow(img_auto, 'InitialMagnification', magnification);
                title('Image Analysis');

                if app.M1_C_Auto.Value == 1
                    
                    Rmin = 30;
                    Rmax = 55;
                    %[centersBright, radiiBright] = imfindcircles(A,[Rmin Rmax],'ObjectPolarity','bright');
                    [centersDark, radiiDark] = imfindcircles(img_auto,[Rmin Rmax],'ObjectPolarity','dark');
                    %viscircles(centersBright, radiiBright,'Color','b'); %Para circulos claros
                    viscircles(centersDark, radiiDark,'LineStyle','--','LineWidth',1);
                    z=size(centersDark,1);
                    
                    cont=1;

                    orden=sortrows(centersDark()); % orden https://la.mathworks.com/help/matlab/math/reshaping-and-rearranging-arrays.html
                    
                    for a=1:1:z %% Genreación de líneas
                           
                       if cont<=z-1
                           drawline('Position',[[orden(cont,:)];[orden(cont+1,:)]],'StripeColor','white','LineWidth', 1.5, 'LabelAlpha',0);
                       end
                       
                       cont=cont+1;
                    
                    end
                    
                    for a=1:1:z %% Genreación de puntos
                       
                       p(a)=drawpoint('Position',[orden(a,:)],'Color', 'r', 'LabelAlpha',0);
                       h(a,:)=p(1,a).Position(1,:)
                       P_world(a,:)= pointsToWorld(cameraParams, R, t, h(a,:))
                       %drawpoint('Position',[orden(a,:)],'Color', 'r', 'LabelAlpha',0, 'Label', sprintf('%.2f',P_world(a,:)));
                       
                       if app.Coor.Value==1
                           text(orden(a,1)+app.ValX.Value+20,orden(a,2)-20-app.ValY.Value,sprintf('%.2f',P_world(a,1)),'Color', 'r','FontSize',app.SizeL.Value,'fontweight','bold');
                           text(orden(a,1)+app.ValX.Value+150,orden(a,2)-20-app.ValY.Value,',','Color', 'r','FontSize',app.SizeL.Value,'fontweight','bold');
                           text(orden(a,1)+app.ValX.Value+180,orden(a,2)-20-app.ValY.Value,sprintf('%.2f',P_world(a,2)),'Color', 'r','FontSize',app.SizeL.Value,'fontweight','bold');
                       end
                    end
                    
                    app.CPuntos.Data=P_world(a,:),'%.2f';

                        [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                        %distance1 = norm(P0_world - P1_world);
                end

                if app.M2.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0);
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0);

                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0);
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0);

                    P0= h0.Position(1,:); %Points coordinates (points)
                    P1= h1.Position(1,:);
                    P2= h2.Position(1,:); %Points coordinates (points)
                    P3= h3.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams, R, t, P3);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=0;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='red';

                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0);
                end

                if app.M3.Value==1
                    h0 = drawpoint('Label', 'P1', 'LabelAlpha',0);
                    h1 = drawpoint('Label', 'P2', 'LabelAlpha',0);

                    h2 = drawpoint('Label', 'P3', 'LabelAlpha',0);
                    h3 = drawpoint('Label', 'P4', 'LabelAlpha',0);

                    h4 = drawpoint('Label', 'P5', 'LabelAlpha',0);
                    h5 = drawpoint('Label', 'P6', 'LabelAlpha',0);

                    P0= h0.Position(1,:); %Points coordinates (points)
                    P1= h1.Position(1,:);
                    P2= h2.Position(1,:); %Points coordinates (points)
                    P3= h3.Position(1,:);
                    P4= h4.Position(1,:); %Points coordinates (points)
                    P5= h5.Position(1,:);

                    % Convert to world coordinates.
                    P0_world  = pointsToWorld(cameraParams, R, t, P0);
                    P1_world  = pointsToWorld(cameraParams, R, t, P1);
                    P2_world  = pointsToWorld(cameraParams, R, t, P2);
                    P3_world  = pointsToWorld(cameraParams, R, t, P3);
                    P4_world  = pointsToWorld(cameraParams, R, t, P4);
                    P5_world  = pointsToWorld(cameraParams, R, t, P5);

                    %scatter(x3,y3,'filled','DisplayName','2017')
                    % Compute the distance
                    [~, cameraLocation] = extrinsicsToCameraPose(R, t);
                    distance1 = norm(P0_world - P1_world);
                    distance2 = norm(P2_world - P3_world);
                    distance3 = norm(P4_world - P5_world);

                    app.Dis1.Value=distance1;
                    app.Dis2.Value=distance2;
                    app.Dis3.Value=distance3;

                    app.Dis1.BackgroundColor='green';
                    app.Dis2.BackgroundColor='green';
                    app.Dis3.BackgroundColor='green';

                    drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'D1', 'LabelAlpha',0);
                    drawline('Position',[P2(1,1),P2(1,2);P3(1,1),P3(1,2)],'StripeColor','white','Label', 'D2', 'LabelAlpha',0);
                    drawline('Position',[P4(1,1),P4(1,2);P5(1,1),P5(1,2)],'StripeColor','white','Label', 'D3', 'LabelAlpha',0);
                end

            end


        end

        % Value changed function: RadioMin
        function RadioMinValueChanged(app, event)
            valueRMin = app.RadioMin.Value;
            
            if valueRMin <= 5
                app.RadioMin.Value = 6;
            end
            
            if valueRMin >= app.RadioMax.Value
                app.RadioMin.Value = app.RadioMax.Value - 1;
            end
        end

        % Value changed function: RadioMax
        function RadioMaxValueChanged(app, event)
            valueRMax = app.RadioMax.Value;
            
            if valueRMax <= app.RadioMin.Value
                app.RadioMax.Value = app.RadioMin.Value + 1;
            end
            
        end

        % Button pushed function: GuardarCoo
        function GuardarCooButtonPushed(app, event)
            global P_world;

            Path = uigetdir('C:\');
            Name= '\Coordenadas.mat';

            save(strcat(Path,Name),'P_world');

            app.GuardarCoo.BackgroundColor='green';
        end

        % Button pushed function: Imagen_cin_2
        function Imagen_cin_2ButtonPushed(app, event)
            global img_cin_noCal magnification a_cin_noCal cont_cin_noCal

            close all;
            magnification = 25;
            tipos = {'*.jpg; *.jpeg; *.vmp; *.png'};
            
            if cont_cin_noCal==0
                [file, path] = uigetfile(tipos,'Select a image');
            else
                [file, path] = uigetfile(tipos,'Select a image',a_cin_noCal);
            end
            
            if  isequal(file,0)
                disp('Selection canceled');
            else
                imagepath = fullfile(path,file);
                img_cin_noCal = imread (imagepath);
                %uax = uiaxes;
                %imshow(img)
                %hold (app.UIAxes,"on")

                app.Derecha_2.Enable='on';
                app.Izquierda_2.Enable='on';
                app.Imagen_cin_2.BackgroundColor='green';
                app.Derecha_2.BackgroundColor='cyan';
                app.Izquierda_2.BackgroundColor='cyan';
                
                cont_cin_noCal=1;
                a_cin_noCal=path;

            end
        end

        % Button pushed function: Izquierda_2
        function Izquierda_2ButtonPushed(app, event)
            global img_cin_noCal magnification Crosshair_k_WO
            
            close all;
          
                figure; imshow(img_cin_noCal, 'InitialMagnification', magnification);
                title('Undistorted Image');

                h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P0= h0.Position(1,:); %Points coordinates (points)
                    
                if Crosshair_k_WO==1
                   drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                    
                h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P1= h1.Position(1,:);
                drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');

                %Calculo de posición
                Pnuevo(1,1)=P1(1,1); % Coordenadas en x
                Pnuevo(1,2)=P0(1,2); % Coordenadas en z
                pos(1,1)= norm(P0-Pnuevo); %Distancia en x
                pos(1,2)=norm(Pnuevo-P1);  %Distancia en z

                z_cin=pos(1,2);% eje z es el eje vertical
                x_cin=pos(1,1); % También puede ser el eje y
                y_cin=0;% No esta en el espacio

                %---Cálculo en pixeles---%
                k_pix=(2*sqrt(x_cin^2+y_cin^2))/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
                r_pix=1/k_pix; %Radio de la curva en pixeles
                theta_cin_pix=acosd(1-(k_pix*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación

                P_ext(1,1)=P0(1,1)-r_pix; % Se añade coordenadas al punto del radio del circulo
                P_ext(1,2)=P0(1,2);

                drawline('Position',[P0(1,1),P0(1,2);P_ext(1,1),P_ext(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P_ext(1,1),P_ext(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');


                C= norm(P0 - P1);% Points distance 'C'
                B= norm(P0 - P_ext);%'B'
                A= norm(P1 - P_ext);%'A'
                
                app.alpha_dist_2.Value=C;
                app.beta_dist_2.Value=B;
                app.phi_dist_2.Value=A;

                images.roi.Circle(gca,'Center',[P_ext(1,1) P_ext(1,2)],'Radius',B,"InteractionsAllowed",'none');

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));

                app.alpha_3.Value=Alpha;
                app.beta_3.Value=Beta;
                app.phi_3.Value=Phi;
                %app.angulo_2.Value=theta_cin_pix;


            app.x.BackgroundColor='green';
            app.y.BackgroundColor='green';
            app.z.BackgroundColor='green';
            app.Radio.BackgroundColor='magenta';
            app.angulo.BackgroundColor='magenta';
            app.CalculateButton.BackgroundColor='green';

            app.alpha_2.BackgroundColor='green';
            app.beta_2.BackgroundColor='green';
            app.phi_2.BackgroundColor='magenta';
            app.alpha_dist.BackgroundColor='green';
            app.beta_dist.BackgroundColor='magenta';
            app.phi_dist.BackgroundColor='green';
        end

        % Button pushed function: Derecha_2
        function Derecha_2ButtonPushed(app, event)
            global img_cin_noCal magnification Crosshair_k_WO
            
            close all;

                figure; imshow(img_cin_noCal, 'InitialMagnification', magnification);
                title('Undistorted Image');

                h0 = drawpoint('Label', 'P1', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P0= h0.Position(1,:); %Points coordinates (points)
                    
                if Crosshair_k_WO==1
                   drawcrosshair('Position',[P0(1,1) P0(1,2)],'LineWidth',1,'Color','r',"InteractionsAllowed",'none');
                end
                    
                h1 = drawpoint('Label', 'P2', 'LabelAlpha',0,"InteractionsAllowed",'none');
                P1= h1.Position(1,:);
                drawline('Position',[P0(1,1),P0(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'C', 'LabelAlpha',0,"InteractionsAllowed",'none');

                %Calculo de posición
                Pnuevo(1,1)=P1(1,1); % Coordenadas en x
                Pnuevo(1,2)=P0(1,2); % Coordenadas en z
                pos(1,1)= norm(P0-Pnuevo); %Distancia en x
                pos(1,2)=norm(Pnuevo-P1);  %Distancia en z

                z_cin=pos(1,2);% eje z es el eje vertical
                x_cin=pos(1,1); % También puede ser el eje y
                y_cin=0;% No esta en el espacio

                %---Cálculo en pixeles---%
                k_pix=(2*sqrt(x_cin^2+y_cin^2))/(x_cin^2+y_cin^2+z_cin^2);% K: Inversa del radio de la curva
                r_pix=1/k_pix; %Radio de la curva en pixeles
                theta_cin_pix=acosd(1-(k_pix*sqrt(x_cin^2+y_cin^2))); %Ángulo de deformación


                P_ext(1,1)=r_pix+P0(1,1); % Se añade coordenadas al punto del radio del circulo
                P_ext(1,2)=P0(1,2);

                drawline('Position',[P0(1,1),P0(1,2);P_ext(1,1),P_ext(1,2)],'StripeColor','white','Label', 'B', 'LabelAlpha',0,"InteractionsAllowed",'none');
                drawline('Position',[P_ext(1,1),P_ext(1,2);P1(1,1),P1(1,2)],'StripeColor','white','Label', 'A', 'LabelAlpha',0,"InteractionsAllowed",'none');

                C= norm(P0 - P1);% Points distance 'C'
                B= norm(P0 - P_ext);%'B'
                A= norm(P1 - P_ext);%'A'

                app.alpha_dist_2.Value=C;
                app.beta_dist_2.Value=B;
                app.phi_dist_2.Value=A;

                images.roi.Circle(gca,'Center',[P_ext(1,1) P_ext(1,2)],'Radius',B,"InteractionsAllowed",'none');

                Alpha= acosd((A^2-B^2-C^2)/(-2*B*C));
                Beta= acosd((B^2-A^2-C^2)/(-2*A*C));
                Phi= acosd((C^2-B^2-A^2)/(-2*B*A));

                app.alpha_3.Value=Alpha;
                app.beta_3.Value=Beta;
                app.phi_3.Value=Phi;
                %app.angulo_2.Value=theta_cin_pix;


            app.x.BackgroundColor='green';
            app.y.BackgroundColor='green';
            app.z.BackgroundColor='green';
            app.Radio.BackgroundColor='magenta';
            app.angulo.BackgroundColor='magenta';
            app.CalculateButton.BackgroundColor='green';

            app.alpha_2.BackgroundColor='green';
            app.beta_2.BackgroundColor='green';
            app.phi_2.BackgroundColor='magenta';
            app.alpha_dist.BackgroundColor='green';
            app.beta_dist.BackgroundColor='magenta';
            app.phi_dist.BackgroundColor='green';
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            global r2
            x_cin_2=app.x_2.Value;
            y_cin_2=app.y_2.Value;
            z_cin_2=app.z_2.Value;

            k2=(2*(x_cin_2^2+y_cin_2^2)^0.5)/(x_cin_2^2+y_cin_2^2+z_cin_2^2);% K: Inversa del radio de la curva
            r2=1/k2; %Radio de la curva,
            theta_cin_2=acosd(1-(k2*sqrt(x_cin_2^2+y_cin_2^2))); %Ángulo de deformación

            app.Radio_2.Value=r2;
            app.angulo_2.Value=theta_cin_2;

            app.Radio_2.BackgroundColor='green';
            app.angulo_2.BackgroundColor='green';

        end

        % Value changed function: Crosshair_Dis
        function Crosshair_DisValueChanged(app, event)
            global Crosshair_Dis
            
            Crosshair_Dis = app.Crosshair_Dis.Value;
            
        end

        % Value changed function: Crosshair_angle
        function Crosshair_angleValueChanged(app, event)
            global Crosshair_angle
            
            Crosshair_angle = app.Crosshair_angle.Value;
            
        end

        % Value changed function: Crosshair_coor
        function Crosshair_coorValueChanged(app, event)
            global Crosshair_coor
            
            Crosshair_coor = app.Crosshair_coor.Value;
            
        end

        % Value changed function: Crosshair_K
        function Crosshair_KValueChanged(app, event)
            global Crosshair_K
            Crosshair_K = app.Crosshair_K.Value;
            
        end

        % Value changed function: Crosshair_k_WO
        function Crosshair_k_WOValueChanged(app, event)
            global Crosshair_k_WO
            
            Crosshair_k_WO = app.Crosshair_k_WO.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalibratorTab = uitab(app.TabGroup);
            app.CalibratorTab.Title = 'Calibrator';
            app.DatosCal = uipanel(app.CalibratorTab);
            app.DatosCal.Title = 'Graphical representation of calibration';
            app.UIAxes = uiaxes(app.DatosCal);
            app.UIAxes_2 = uiaxes(app.DatosCal);
            app.InformationPanel = uipanel(app.CalibratorTab);
            app.InformationPanel.Title = 'Information';
            app.EditField = uieditfield(app.InformationPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.InformationPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.UploadinfoPanel = uipanel(app.CalibratorTab);
            app.UploadinfoPanel.Title = 'Upload info';
            app.DimensionesdelpatrnmmLabel = uilabel(app.UploadinfoPanel);
            app.DimensionesdelpatrnmmLabel.Text = 'Dimensions of the pattern (mm)';
            app.Patron = uieditfield(app.UploadinfoPanel, 'numeric');
            app.TamaodelpixeldelacmaramLabel = uilabel(app.UploadinfoPanel);
            app.TamaodelpixeldelacmaramLabel.Text = 'Camera pixel size (µm)';
            app.Pixel_size = uieditfield(app.UploadinfoPanel, 'numeric');
            app.ErrormLabel = uilabel(app.UploadinfoPanel);
            app.ErrormLabel.Text = 'Error (µm)';
            app.Error = uieditfield(app.UploadinfoPanel, 'numeric');
            app.Error.Editable = 'off';
            app.CargarDatos = uipanel(app.CalibratorTab);
            app.CargarDatos.Title = 'Load Images';
            app.ImagesButton = uibutton(app.CargarDatos, 'push');
            app.ImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesButtonPushed, true);
            app.ImagesButton.Text = 'Images';
            app.CalibrateButton = uibutton(app.CargarDatos, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SaveButton = uibutton(app.CargarDatos, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CargarDatos_2 = uipanel(app.CalibratorTab);
            app.CargarDatos_2.Title = 'Upload Parameters';
            app.CParmetros = uibutton(app.CargarDatos_2, 'push');
            app.CParmetros.ButtonPushedFcn = createCallbackFcn(app, @CParmetrosButton, true);
            app.CParmetros.Text = 'Parameters';
            app.DevelopedbyIsraelSantacruzMondragnLabel_2 = uilabel(app.CalibratorTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel_2.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.LoadImagePanel = uipanel(app.AutoTab);
            app.LoadImagePanel.Title = 'Load Image';
            app.ImagenButton_Auto = uibutton(app.LoadImagePanel, 'push');
            app.ImagenButton_Auto.ButtonPushedFcn = createCallbackFcn(app, @ImagenButton_AutoPushed, true);
            app.ImagenButton_Auto.Text = 'Image';
            app.ProcessimageLabel = uilabel(app.LoadImagePanel);
            app.ProcessimageLabel.Text = 'Process image';
            app.No_Auto = uibutton(app.LoadImagePanel, 'push');
            app.No_Auto.ButtonPushedFcn = createCallbackFcn(app, @No_AutoButtonPushed, true);
            app.No_Auto.Text = 'Process';
            app.FitPanel = uipanel(app.AutoTab);
            app.FitPanel.Title = 'Fit';
            app.OffsetenyLabel = uilabel(app.FitPanel);
            app.OffsetenyLabel.Text = 'Offset y';
            app.Offset_y = uieditfield(app.FitPanel, 'numeric');
            app.OffsetenxLabel = uilabel(app.FitPanel);
            app.OffsetenxLabel.Text = 'Offset x';
            app.Offset_x = uieditfield(app.FitPanel, 'numeric');
            app.BinarizarLabel = uilabel(app.FitPanel);
            app.BinarizarLabel.Text = 'Binarize ';
            app.Bina = uieditfield(app.FitPanel, 'numeric');
            app.RuidoLabel = uilabel(app.FitPanel);
            app.RuidoLabel.Text = 'Noise ';
            app.Ruido = uieditfield(app.FitPanel, 'numeric');
            app.RadioMinLabel = uilabel(app.FitPanel);
            app.RadioMinLabel.Text = 'Min. Radius';
            app.RadioMin = uieditfield(app.FitPanel, 'numeric');
            app.RadioMin.ValueChangedFcn = createCallbackFcn(app, @RadioMinValueChanged, true);
            app.RadioMaxLabel = uilabel(app.FitPanel);
            app.RadioMaxLabel.Text = 'Max. Radius';
            app.RadioMax = uieditfield(app.FitPanel, 'numeric');
            app.RadioMax.ValueChangedFcn = createCallbackFcn(app, @RadioMaxValueChanged, true);
            app.Label_2 = uilabel(app.FitPanel);
            app.Label_2.Text = '-------------------------------------------------------------';
            app.HistoenxLabel = uilabel(app.FitPanel);
            app.HistoenxLabel.Text = 'Histo. x';
            app.HX = uieditfield(app.FitPanel, 'numeric');
            app.HistoenyLabel = uilabel(app.FitPanel);
            app.HistoenyLabel.Text = 'Histo. y';
            app.HY = uieditfield(app.FitPanel, 'numeric');
            app.ValXpixLabel = uilabel(app.FitPanel);
            app.ValXpixLabel.Text = 'Val. X [pix]';
            app.ValX = uieditfield(app.FitPanel, 'numeric');
            app.ValYpixLabel = uilabel(app.FitPanel);
            app.ValYpixLabel.Text = 'Val. Y [pix]';
            app.ValY = uieditfield(app.FitPanel, 'numeric');
            app.SizeletterLabel = uilabel(app.FitPanel);
            app.SizeletterLabel.Text = 'Size letter';
            app.SizeL = uieditfield(app.FitPanel, 'numeric');
            app.Coor = uicheckbox(app.FitPanel);
            app.Coor.Text = 'Coordinates';
            app.InformacinPanel_Auto = uipanel(app.AutoTab);
            app.InformacinPanel_Auto.Title = 'Information';
            app.EditField_Auto2 = uieditfield(app.InformacinPanel_Auto, 'text');
            app.EditField_Auto2.Editable = 'off';
            app.EditField_Auto = uieditfield(app.InformacinPanel_Auto, 'text');
            app.EditField_Auto.Editable = 'off';
            app.ToolformeasuringdistanceandangleAutomaticLabel = uilabel(app.AutoTab);
            app.ToolformeasuringdistanceandangleAutomaticLabel.Text = 'Tool for measuring distance and angle (Automatic)';
            app.TypeofmeasurementPanel = uipanel(app.AutoTab);
            app.TypeofmeasurementPanel.Title = 'Type of measurement';
            app.M1_Auto = uicheckbox(app.TypeofmeasurementPanel);
            app.M1_Auto.ValueChangedFcn = createCallbackFcn(app, @M1_AutoValueChanged, true);
            app.M1_Auto.Text = 'Distance';
            app.M2_Auto = uicheckbox(app.TypeofmeasurementPanel);
            app.M2_Auto.ValueChangedFcn = createCallbackFcn(app, @M2_AutoValueChanged, true);
            app.M2_Auto.Text = 'Angle';
            app.Lamp_4Label_2 = uilabel(app.TypeofmeasurementPanel);
            app.Lamp_4Label_2.Text = '';
            app.Lamp_4_Auto = uilamp(app.TypeofmeasurementPanel);
            app.Lamp_5Label_2 = uilabel(app.TypeofmeasurementPanel);
            app.Lamp_5Label_2.Text = '';
            app.Lamp_5_Auto = uilamp(app.TypeofmeasurementPanel);
            app.Lamp_5_Auto.Enable = 'off';
            app.M1_C_Auto = uicheckbox(app.TypeofmeasurementPanel);
            app.M1_C_Auto.ValueChangedFcn = createCallbackFcn(app, @M1_C_AutoValueChanged, true);
            app.M1_C_Auto.Text = 'Circles';
            app.M1_O_Auto = uicheckbox(app.TypeofmeasurementPanel);
            app.M1_O_Auto.ValueChangedFcn = createCallbackFcn(app, @M1_O_AutoValueChanged, true);
            app.M1_O_Auto.Text = 'Rectangle';
            app.CPuntos = uitable(app.AutoTab);
            app.CPuntos.ColumnName = {'X (mm)'; 'Y (mm)'};
            app.CPuntos.RowName = {};
            app.CPuntos.ColumnEditable = true;
            app.CargarDatos_3 = uipanel(app.AutoTab);
            app.CargarDatos_3.Title = 'Save Coordinates';
            app.GuardarCoo = uibutton(app.CargarDatos_3, 'push');
            app.GuardarCoo.ButtonPushedFcn = createCallbackFcn(app, @GuardarCooButtonPushed, true);
            app.GuardarCoo.Text = 'Save';
            app.DevelopedbyIsraelSantacruzMondragnLabel_7 = uilabel(app.AutoTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel_7.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.AngleWOCalibTab = uitab(app.TabGroup);
            app.AngleWOCalibTab.Title = 'Angle W/O Calib.';
            app.ResultsPanel = uipanel(app.AngleWOCalibTab);
            app.ResultsPanel.Title = 'Results';
            app.nguloentreCBLabel = uilabel(app.ResultsPanel);
            app.nguloentreCBLabel.Text = 'Angle between C & B (°)';
            app.alpha = uieditfield(app.ResultsPanel, 'numeric');
            app.alpha.Editable = 'off';
            app.nguloentreCALabel = uilabel(app.ResultsPanel);
            app.nguloentreCALabel.Text = 'Angle betweenC & A (°)';
            app.beta = uieditfield(app.ResultsPanel, 'numeric');
            app.beta.Editable = 'off';
            app.nguloentreABLabel = uilabel(app.ResultsPanel);
            app.nguloentreABLabel.Text = 'Angle between A & B (°)';
            app.phi = uieditfield(app.ResultsPanel, 'numeric');
            app.phi.Editable = 'off';
            app.LoadImagePanel_2 = uipanel(app.AngleWOCalibTab);
            app.LoadImagePanel_2.Title = 'Load Image';
            app.ImageButton = uibutton(app.LoadImagePanel_2, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImagenAngle, true);
            app.ImageButton.Text = 'Image';
            app.DoyouwanttooverlayanimageLabel = uilabel(app.LoadImagePanel_2);
            app.DoyouwanttooverlayanimageLabel.Text = 'Do you want to overlay an image?';
            app.Si_angle = uibutton(app.LoadImagePanel_2, 'push');
            app.Si_angle.ButtonPushedFcn = createCallbackFcn(app, @SiButton_angle, true);
            app.Si_angle.Text = 'Yes';
            app.No_angle = uibutton(app.LoadImagePanel_2, 'push');
            app.No_angle.ButtonPushedFcn = createCallbackFcn(app, @NoButton_angle, true);
            app.No_angle.Text = 'No';
            app.Crosshair_angle = uicheckbox(app.LoadImagePanel_2);
            app.Crosshair_angle.ValueChangedFcn = createCallbackFcn(app, @Crosshair_angleValueChanged, true);
            app.Crosshair_angle.Text = 'Enable Crosshair';
            app.InformationPanel_2 = uipanel(app.AngleWOCalibTab);
            app.InformationPanel_2.Title = 'Information';
            app.EditField_3 = uieditfield(app.InformationPanel_2, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.InformationPanel_2, 'text');
            app.EditField_4.Editable = 'off';
            app.ToolformeasuringanglesLabel = uilabel(app.AngleWOCalibTab);
            app.ToolformeasuringanglesLabel.Text = 'Tool for measuring angles';
            app.DevelopedbyIsraelSantacruzMondragnLabel_3 = uilabel(app.AngleWOCalibTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel_3.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.DistanceTab = uitab(app.TabGroup);
            app.DistanceTab.Title = 'Distance';
            app.LoadImagePanel_3 = uipanel(app.DistanceTab);
            app.LoadImagePanel_3.Title = 'Load Image';
            app.ImageButton_2 = uibutton(app.LoadImagePanel_3, 'push');
            app.ImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImagenDist, true);
            app.ImageButton_2.Text = 'Image';
            app.DoyouwanttooverlayanimageLabel_2 = uilabel(app.LoadImagePanel_3);
            app.DoyouwanttooverlayanimageLabel_2.Text = 'Do you want to overlay an image?';
            app.Si = uibutton(app.LoadImagePanel_3, 'push');
            app.Si.ButtonPushedFcn = createCallbackFcn(app, @SiButton, true);
            app.Si.Text = 'Yes';
            app.No = uibutton(app.LoadImagePanel_3, 'push');
            app.No.ButtonPushedFcn = createCallbackFcn(app, @NoButton, true);
            app.No.Text = 'Process';
            app.Crosshair_Dis = uicheckbox(app.LoadImagePanel_3);
            app.Crosshair_Dis.ValueChangedFcn = createCallbackFcn(app, @Crosshair_DisValueChanged, true);
            app.Crosshair_Dis.Text = 'Enable Crosshair';
            app.ResultsPanel_2 = uipanel(app.DistanceTab);
            app.ResultsPanel_2.Title = 'Results';
            app.Distancia1mmLabel = uilabel(app.ResultsPanel_2);
            app.Distancia1mmLabel.Text = 'Distance 1 (mm)';
            app.Dis1 = uieditfield(app.ResultsPanel_2, 'numeric');
            app.Dis1.Editable = 'off';
            app.Distancia2mmLabel = uilabel(app.ResultsPanel_2);
            app.Distancia2mmLabel.Text = 'Distance 2 (mm)';
            app.Dis2 = uieditfield(app.ResultsPanel_2, 'numeric');
            app.Dis2.Editable = 'off';
            app.Distancia3mmLabel = uilabel(app.ResultsPanel_2);
            app.Distancia3mmLabel.Text = 'Distance 3 (mm)';
            app.Dis3 = uieditfield(app.ResultsPanel_2, 'numeric');
            app.Dis3.Editable = 'off';
            app.DevelopedbyIsraelSantacruzMondragnLabel = uilabel(app.DistanceTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.InformationPanel_3 = uipanel(app.DistanceTab);
            app.InformationPanel_3.Title = 'Information';
            app.EditField_5 = uieditfield(app.InformationPanel_3, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.InformationPanel_3, 'text');
            app.EditField_6.Editable = 'off';
            app.ToolformeasuringdistanceLabel = uilabel(app.DistanceTab);
            app.ToolformeasuringdistanceLabel.Text = 'Tool for measuring distance';
            app.NumberofmeasurementsPanel_2 = uipanel(app.DistanceTab);
            app.NumberofmeasurementsPanel_2.Title = 'Number of measurements';
            app.M1 = uicheckbox(app.NumberofmeasurementsPanel_2);
            app.M1.ValueChangedFcn = createCallbackFcn(app, @M1ValueChanged, true);
            app.M1.Text = '1 Measurement';
            app.M2 = uicheckbox(app.NumberofmeasurementsPanel_2);
            app.M2.ValueChangedFcn = createCallbackFcn(app, @M2ValueChanged, true);
            app.M2.Text = '2 Measurements';
            app.M3 = uicheckbox(app.NumberofmeasurementsPanel_2);
            app.M3.ValueChangedFcn = createCallbackFcn(app, @M3ValueChanged, true);
            app.M3.Text = '3 Measurements';
            app.Label = uilabel(app.NumberofmeasurementsPanel_2);
            app.Label.Text = '';
            app.Lamp = uilamp(app.NumberofmeasurementsPanel_2);
            app.Lamp_3Label = uilabel(app.NumberofmeasurementsPanel_2);
            app.Lamp_3Label.Text = '';
            app.Lamp_3 = uilamp(app.NumberofmeasurementsPanel_2);
            app.Lamp_2Label = uilabel(app.NumberofmeasurementsPanel_2);
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.NumberofmeasurementsPanel_2);
            app.CoordinatesTab = uitab(app.TabGroup);
            app.CoordinatesTab.Title = 'Coordinates';
            app.LoadImagePanel_6 = uipanel(app.CoordinatesTab);
            app.LoadImagePanel_6.Title = 'Load Image';
            app.ImageButton_3 = uibutton(app.LoadImagePanel_6, 'push');
            app.ImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImagenCoord, true);
            app.ImageButton_3.Text = 'Image';
            app.Procesar = uibutton(app.LoadImagePanel_6, 'push');
            app.Procesar.ButtonPushedFcn = createCallbackFcn(app, @Process_imag, true);
            app.Procesar.Text = 'Process';
            app.Crosshair_coor = uicheckbox(app.LoadImagePanel_6);
            app.Crosshair_coor.ValueChangedFcn = createCallbackFcn(app, @Crosshair_coorValueChanged, true);
            app.Crosshair_coor.Text = 'Enable Crosshair';
            app.ResultsPanel_3 = uipanel(app.CoordinatesTab);
            app.ResultsPanel_3.Title = 'Results';
            app.P1xymmLabel = uilabel(app.ResultsPanel_3);
            app.P1xymmLabel.Text = 'P1 (x,y) [mm]';
            app.Coord_1_x = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_1_x.Editable = 'off';
            app.Coord_1_y = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_1_y.Editable = 'off';
            app.Coord_2_y = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_2_y.Editable = 'off';
            app.P2xymmLabel = uilabel(app.ResultsPanel_3);
            app.P2xymmLabel.Text = 'P2 (x,y) [mm]';
            app.Coord_2_x = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_2_x.Editable = 'off';
            app.Coord_3_y = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_3_y.Editable = 'off';
            app.Coord_4_y = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_4_y.Editable = 'off';
            app.P3xymmLabel = uilabel(app.ResultsPanel_3);
            app.P3xymmLabel.Text = 'P3 (x,y) [mm]';
            app.Coord_3_x = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_3_x.Editable = 'off';
            app.P4xymmLabel = uilabel(app.ResultsPanel_3);
            app.P4xymmLabel.Text = 'P4 (x,y) [mm]';
            app.Coord_4_x = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_4_x.Editable = 'off';
            app.Coord_5_y = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_5_y.Editable = 'off';
            app.P5xymmLabel = uilabel(app.ResultsPanel_3);
            app.P5xymmLabel.Text = 'P5 (x,y) [mm]';
            app.Coord_5_x = uieditfield(app.ResultsPanel_3, 'numeric');
            app.Coord_5_x.Editable = 'off';
            app.InformationPanel_6 = uipanel(app.CoordinatesTab);
            app.InformationPanel_6.Title = 'Information';
            app.EditField_9 = uieditfield(app.InformationPanel_6, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.InformationPanel_6, 'text');
            app.EditField_10.Editable = 'off';
            app.TooltolocatepointsofinteresLabel = uilabel(app.CoordinatesTab);
            app.TooltolocatepointsofinteresLabel.Text = 'Tool to locate points of interes';
            app.NumberofmeasurementsPanel = uipanel(app.CoordinatesTab);
            app.NumberofmeasurementsPanel.Title = 'Number of measurements';
            app.M1_2 = uicheckbox(app.NumberofmeasurementsPanel);
            app.M1_2.ValueChangedFcn = createCallbackFcn(app, @M1_Coord, true);
            app.M1_2.Text = '2 Points';
            app.M2_2 = uicheckbox(app.NumberofmeasurementsPanel);
            app.M2_2.ValueChangedFcn = createCallbackFcn(app, @M2_Coord, true);
            app.M2_2.Text = '3 Points';
            app.M3_2 = uicheckbox(app.NumberofmeasurementsPanel);
            app.M3_2.ValueChangedFcn = createCallbackFcn(app, @M3_Coord, true);
            app.M3_2.Text = '4 Points';
            app.Lamp_4Label = uilabel(app.NumberofmeasurementsPanel);
            app.Lamp_4Label.Text = '';
            app.Lamp_2_coo = uilamp(app.NumberofmeasurementsPanel);
            app.Lamp_5Label = uilabel(app.NumberofmeasurementsPanel);
            app.Lamp_5Label.Text = '';
            app.Lamp_3_coo = uilamp(app.NumberofmeasurementsPanel);
            app.Lamp_6Label = uilabel(app.NumberofmeasurementsPanel);
            app.Lamp_6Label.Text = '';
            app.Lamp_4_coo = uilamp(app.NumberofmeasurementsPanel);
            app.M4_2 = uicheckbox(app.NumberofmeasurementsPanel);
            app.M4_2.ValueChangedFcn = createCallbackFcn(app, @M4_Coord, true);
            app.M4_2.Text = '5 Points';
            app.Lamp_6Label_2 = uilabel(app.NumberofmeasurementsPanel);
            app.Lamp_6Label_2.Text = '';
            app.Lamp_5_coo = uilamp(app.NumberofmeasurementsPanel);
            app.DevelopedbyIsraelSantacruzMondragnLabel_6 = uilabel(app.CoordinatesTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel_6.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.UIAxes2 = uiaxes(app.KinematicsTab);
            title(app.UIAxes2, 'Frame of reference')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ResultsDistancePanel = uipanel(app.KinematicsTab);
            app.ResultsDistancePanel.Title = 'Results (Distance)';
            app.DistanciadeCmmLabel = uilabel(app.ResultsDistancePanel);
            app.DistanciadeCmmLabel.Text = 'Distance of C (mm)';
            app.alpha_dist = uieditfield(app.ResultsDistancePanel, 'numeric');
            app.alpha_dist.Editable = 'off';
            app.DistanciadeBmmLabel = uilabel(app.ResultsDistancePanel);
            app.DistanciadeBmmLabel.Text = 'Distance of B (mm)';
            app.beta_dist = uieditfield(app.ResultsDistancePanel, 'numeric');
            app.beta_dist.Editable = 'off';
            app.DistanciadeAmmLabel = uilabel(app.ResultsDistancePanel);
            app.DistanciadeAmmLabel.Text = 'Distance of A (mm)';
            app.phi_dist = uieditfield(app.ResultsDistancePanel, 'numeric');
            app.phi_dist.Editable = 'off';
            app.LoadImagePanel_4 = uipanel(app.KinematicsTab);
            app.LoadImagePanel_4.Title = 'Load Image';
            app.Imagen_cin = uibutton(app.LoadImagePanel_4, 'push');
            app.Imagen_cin.ButtonPushedFcn = createCallbackFcn(app, @Imagen_cinButton, true);
            app.Imagen_cin.Text = 'Image';
            app.ThemovementistowardsLabel = uilabel(app.LoadImagePanel_4);
            app.ThemovementistowardsLabel.Text = 'The movement is towards...';
            app.Izquierda = uibutton(app.LoadImagePanel_4, 'push');
            app.Izquierda.ButtonPushedFcn = createCallbackFcn(app, @IzquierdaButton, true);
            app.Izquierda.Text = 'Left';
            app.Derecha = uibutton(app.LoadImagePanel_4, 'push');
            app.Derecha.ButtonPushedFcn = createCallbackFcn(app, @DerechaButton, true);
            app.Derecha.Text = 'Right';
            app.Crosshair_K = uicheckbox(app.LoadImagePanel_4);
            app.Crosshair_K.ValueChangedFcn = createCallbackFcn(app, @Crosshair_KValueChanged, true);
            app.Crosshair_K.Text = 'Enable Crosshair';
            app.InformationPanel_4 = uipanel(app.KinematicsTab);
            app.InformationPanel_4.Title = 'Information';
            app.EditField_7 = uieditfield(app.InformationPanel_4, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.InformationPanel_4, 'text');
            app.EditField_8.Editable = 'off';
            app.Toolfordeterminingthe2DkinematicsofasoftrobotLabel = uilabel(app.KinematicsTab);
            app.Toolfordeterminingthe2DkinematicsofasoftrobotLabel.Text = 'Tool for determining the 2D kinematics of a soft robot';
            app.LoaddataPanel = uipanel(app.KinematicsTab);
            app.LoaddataPanel.Title = 'Load data';
            app.xmmLabel = uilabel(app.LoaddataPanel);
            app.xmmLabel.Text = 'x (mm)';
            app.x = uieditfield(app.LoaddataPanel, 'numeric');
            app.ymmLabel = uilabel(app.LoaddataPanel);
            app.ymmLabel.Text = 'y (mm)';
            app.y = uieditfield(app.LoaddataPanel, 'numeric');
            app.nguloLabel = uilabel(app.LoaddataPanel);
            app.nguloLabel.Text = 'Angle (°)';
            app.angulo = uieditfield(app.LoaddataPanel, 'numeric');
            app.angulo.Editable = 'off';
            app.zmmLabel = uilabel(app.LoaddataPanel);
            app.zmmLabel.Text = 'z (mm)';
            app.z = uieditfield(app.LoaddataPanel, 'numeric');
            app.RadiommLabel = uilabel(app.LoaddataPanel);
            app.RadiommLabel.Text = 'Radius (mm)';
            app.Radio = uieditfield(app.LoaddataPanel, 'numeric');
            app.Radio.Editable = 'off';
            app.CalculateButton = uibutton(app.LoaddataPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calcular, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsAnglePanel = uipanel(app.KinematicsTab);
            app.ResultsAnglePanel.Title = 'Results (Angle)';
            app.nguloentreCBLabel_2 = uilabel(app.ResultsAnglePanel);
            app.nguloentreCBLabel_2.Text = 'Angle between C & B (°)';
            app.alpha_2 = uieditfield(app.ResultsAnglePanel, 'numeric');
            app.alpha_2.Editable = 'off';
            app.nguloentreCALabel_2 = uilabel(app.ResultsAnglePanel);
            app.nguloentreCALabel_2.Text = 'Angle between C & A (°)';
            app.beta_2 = uieditfield(app.ResultsAnglePanel, 'numeric');
            app.beta_2.Editable = 'off';
            app.nguloentreABLabel_2 = uilabel(app.ResultsAnglePanel);
            app.nguloentreABLabel_2.Text = 'Angle between A & B (°)';
            app.phi_2 = uieditfield(app.ResultsAnglePanel, 'numeric');
            app.phi_2.Editable = 'off';
            app.DevelopedbyIsraelSantacruzMondragnLabel_4 = uilabel(app.KinematicsTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel_4.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.KinematicsWOCalibTab = uitab(app.TabGroup);
            app.KinematicsWOCalibTab.Title = 'Kinematics W/O Calib.';
            app.UIAxes2_2 = uiaxes(app.KinematicsWOCalibTab);
            title(app.UIAxes2_2, 'Frame of reference')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.ResultsDistancePanel_2 = uipanel(app.KinematicsWOCalibTab);
            app.ResultsDistancePanel_2.Title = 'Results (Distance)';
            app.DistanciadeCpxLabel = uilabel(app.ResultsDistancePanel_2);
            app.DistanciadeCpxLabel.Text = 'Distance of C (px)';
            app.alpha_dist_2 = uieditfield(app.ResultsDistancePanel_2, 'numeric');
            app.alpha_dist_2.Editable = 'off';
            app.DistanciadeBpxLabel = uilabel(app.ResultsDistancePanel_2);
            app.DistanciadeBpxLabel.Text = 'Distance of B (px)';
            app.beta_dist_2 = uieditfield(app.ResultsDistancePanel_2, 'numeric');
            app.beta_dist_2.Editable = 'off';
            app.DistanciadeApxLabel = uilabel(app.ResultsDistancePanel_2);
            app.DistanciadeApxLabel.Text = 'Distance of A (px)';
            app.phi_dist_2 = uieditfield(app.ResultsDistancePanel_2, 'numeric');
            app.phi_dist_2.Editable = 'off';
            app.LoadImagePanel_5 = uipanel(app.KinematicsWOCalibTab);
            app.LoadImagePanel_5.Title = 'Load Image';
            app.Imagen_cin_2 = uibutton(app.LoadImagePanel_5, 'push');
            app.Imagen_cin_2.ButtonPushedFcn = createCallbackFcn(app, @Imagen_cin_2ButtonPushed, true);
            app.Imagen_cin_2.Text = 'Image';
            app.ThemovementistowardsLabel_2 = uilabel(app.LoadImagePanel_5);
            app.ThemovementistowardsLabel_2.Text = 'The movement is towards...';
            app.Izquierda_2 = uibutton(app.LoadImagePanel_5, 'push');
            app.Izquierda_2.ButtonPushedFcn = createCallbackFcn(app, @Izquierda_2ButtonPushed, true);
            app.Izquierda_2.Text = 'Left';
            app.Derecha_2 = uibutton(app.LoadImagePanel_5, 'push');
            app.Derecha_2.ButtonPushedFcn = createCallbackFcn(app, @Derecha_2ButtonPushed, true);
            app.Derecha_2.Text = 'Right';
            app.Crosshair_k_WO = uicheckbox(app.LoadImagePanel_5);
            app.Crosshair_k_WO.ValueChangedFcn = createCallbackFcn(app, @Crosshair_k_WOValueChanged, true);
            app.Crosshair_k_WO.Text = 'Enable Crosshair';
            app.InformationPanel_5 = uipanel(app.KinematicsWOCalibTab);
            app.InformationPanel_5.Title = 'Information';
            app.EditField_11 = uieditfield(app.InformationPanel_5, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.InformationPanel_5, 'text');
            app.EditField_12.Editable = 'off';
            app.Toolfordeterminingthe2DkinematicsofasoftrobotLabel_2 = uilabel(app.KinematicsWOCalibTab);
            app.Toolfordeterminingthe2DkinematicsofasoftrobotLabel_2.Text = 'Tool for determining the 2D kinematics of a soft robot';
            app.LoaddataPanel_2 = uipanel(app.KinematicsWOCalibTab);
            app.LoaddataPanel_2.Title = 'Load data';
            app.xpxLabel = uilabel(app.LoaddataPanel_2);
            app.xpxLabel.Text = 'x (px)';
            app.x_2 = uieditfield(app.LoaddataPanel_2, 'numeric');
            app.ypxLabel = uilabel(app.LoaddataPanel_2);
            app.ypxLabel.Text = 'y (px)';
            app.y_2 = uieditfield(app.LoaddataPanel_2, 'numeric');
            app.nguloLabel_2 = uilabel(app.LoaddataPanel_2);
            app.nguloLabel_2.Text = 'Angle (°)';
            app.angulo_2 = uieditfield(app.LoaddataPanel_2, 'numeric');
            app.angulo_2.Editable = 'off';
            app.zpxLabel = uilabel(app.LoaddataPanel_2);
            app.zpxLabel.Text = 'z (px)';
            app.z_2 = uieditfield(app.LoaddataPanel_2, 'numeric');
            app.RadiopxLabel = uilabel(app.LoaddataPanel_2);
            app.RadiopxLabel.Text = 'Radius (px)';
            app.Radio_2 = uieditfield(app.LoaddataPanel_2, 'numeric');
            app.Radio_2.Editable = 'off';
            app.CalculateButton_2 = uibutton(app.LoaddataPanel_2, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ResultsAnglePanel_2 = uipanel(app.KinematicsWOCalibTab);
            app.ResultsAnglePanel_2.Title = 'Results (Angle)';
            app.nguloentreCBLabel_3 = uilabel(app.ResultsAnglePanel_2);
            app.nguloentreCBLabel_3.Text = 'Angle between C & B (°)';
            app.alpha_3 = uieditfield(app.ResultsAnglePanel_2, 'numeric');
            app.alpha_3.Editable = 'off';
            app.nguloentreCALabel_3 = uilabel(app.ResultsAnglePanel_2);
            app.nguloentreCALabel_3.Text = 'Angle between C & A (°)';
            app.beta_3 = uieditfield(app.ResultsAnglePanel_2, 'numeric');
            app.beta_3.Editable = 'off';
            app.nguloentreABLabel_3 = uilabel(app.ResultsAnglePanel_2);
            app.nguloentreABLabel_3.Text = 'Angle between A & B (°)';
            app.phi_3 = uieditfield(app.ResultsAnglePanel_2, 'numeric');
            app.phi_3.Editable = 'off';
            app.DevelopedbyIsraelSantacruzMondragnLabel_5 = uilabel(app.KinematicsWOCalibTab);
            app.DevelopedbyIsraelSantacruzMondragnLabel_5.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.ContactTab = uitab(app.TabGroup);
            app.ContactTab.Title = 'Contact';
            app.ContactInfoPanel = uipanel(app.ContactTab);
            app.ContactInfoPanel.Title = 'Contact Info';
            app.DevelopedbyIsraelSantacruzMondragnLabel_8 = uilabel(app.ContactInfoPanel);
            app.DevelopedbyIsraelSantacruzMondragnLabel_8.Text = 'Developed by: Israel Santacruz-Mondragón';
            app.emailisrastacruzgmailcomLabel = uilabel(app.ContactInfoPanel);
            app.emailisrastacruzgmailcomLabel.Text = 'email: isra.stacruz@gmail.com';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppVisonV12_Eng

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end